classdef IV_forwardTDS544Alatest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        VCompButton_2                   matlab.ui.control.StateButton
        Comp_iterEditField              matlab.ui.control.NumericEditField
        Comp_iterEditFieldLabel         matlab.ui.control.Label
        VCompButton                     matlab.ui.control.StateButton
        KiEditField                     matlab.ui.control.NumericEditField
        KiEditFieldLabel                matlab.ui.control.Label
        TolVEditField                   matlab.ui.control.NumericEditField
        TolVEditFieldLabel              matlab.ui.control.Label
        KpEditField                     matlab.ui.control.NumericEditField
        KpEditFieldLabel                matlab.ui.control.Label
        PILabel                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        IVF_ButtonGroup                 matlab.ui.container.ButtonGroup
        SourceDropDownType              matlab.ui.control.DropDown
        IVFRUNButton                    matlab.ui.control.Button
        DeviceEditField                 matlab.ui.control.EditField
        DeviceEditFieldLabel            matlab.ui.control.Label
        FolderPathEditField             matlab.ui.control.EditField
        FolderPathEditFieldLabel        matlab.ui.control.Label
        FileCheckBox                    matlab.ui.control.CheckBox
        MessageTextAreaLabel_2          matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
        MessageTextArea                 matlab.ui.control.TextArea
        MessageTextAreaLabel            matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        NucleoSTM32Tab                  matlab.ui.container.Tab
        SettingsLabel                   matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        STM32ConfigButton               matlab.ui.control.Button
        TerminatorEditField             matlab.ui.control.EditField
        TerminatorEditFieldLabel        matlab.ui.control.Label
        WordLengthDropDown              matlab.ui.control.DropDown
        WordLengthDropDownLabel         matlab.ui.control.Label
        ParityDropDown                  matlab.ui.control.DropDown
        ParityDropDownLabel             matlab.ui.control.Label
        BaudRateEditField               matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel          matlab.ui.control.Label
        StopBitDropDown                 matlab.ui.control.DropDown
        StopBitDropDownLabel            matlab.ui.control.Label
        PortEditField                   matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        OscilloscopeXTab                matlab.ui.container.Tab
        TDS544ALamp                     matlab.ui.control.Lamp
        TDS544ALampLabel                matlab.ui.control.Label
        ConnectTDS544AButton            matlab.ui.control.Button
        XoffEditFieldLabel              matlab.ui.control.Label
        XoffEditField                   matlab.ui.control.NumericEditField
        RecordLengthDropDown            matlab.ui.control.DropDown
        RecordLengthDropDownLabel       matlab.ui.control.Label
        SingleSeq                       matlab.ui.control.Button
        TimeWind_EditField              matlab.ui.control.NumericEditField
        PulseDelayusEditField_2Label_5  matlab.ui.control.Label
        TrigLevel_EditField             matlab.ui.control.NumericEditField
        PulseDelayusEditField_2Label_4  matlab.ui.control.Label
        TrigPos_EditField               matlab.ui.control.NumericEditField
        PulseDelayusEditField_2Label_3  matlab.ui.control.Label
        SourceDropDown_TRG              matlab.ui.control.DropDown
        SourceDropDownLabel             matlab.ui.control.Label
        SlopeDropDown                   matlab.ui.control.DropDown
        SlopeDropDownLabel              matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        Time_EditField                  matlab.ui.control.NumericEditField
        PulseDelayusEditField_2Label_2  matlab.ui.control.Label
        IPosc_EditField                 matlab.ui.control.EditField
        IPEditFieldLabel                matlab.ui.control.Label
        CONFIGXButton                   matlab.ui.control.Button
        GETDATAButton                   matlab.ui.control.Button
        TRIGGERLabel                    matlab.ui.control.Label
        OscilloscopeYTab                matlab.ui.container.Tab
        AttenuazioneLabel               matlab.ui.control.Label
        AttenuazioneDropDown_CH4        matlab.ui.control.DropDown
        AttenuazioneDropDown_4Label     matlab.ui.control.Label
        AttenuazioneDropDown_CH3        matlab.ui.control.DropDown
        AttenuazioneDropDown_3Label     matlab.ui.control.Label
        AttenuazioneDropDown_CH2        matlab.ui.control.DropDown
        AttenuazioneDropDown_CH1        matlab.ui.control.DropDown
        AttenuazioneDropDown_2Label     matlab.ui.control.Label
        ZeroCH4                         matlab.ui.control.NumericEditField
        ZeroCH3                         matlab.ui.control.NumericEditField
        ZeroCH2                         matlab.ui.control.NumericEditField
        ZeroCH1                         matlab.ui.control.NumericEditField
        Auto_ZeroCH4Button              matlab.ui.control.Button
        Auto_ZeroCH3Button              matlab.ui.control.Button
        Auto_ZeroCH2Button              matlab.ui.control.Button
        Auto_ZeroCH1Button              matlab.ui.control.Button
        CONFIGYButton                   matlab.ui.control.Button
        ch4DC                           matlab.ui.control.NumericEditField
        VdivLabel_11                    matlab.ui.control.Label
        ch3DC                           matlab.ui.control.NumericEditField
        VdivLabel_10                    matlab.ui.control.Label
        ch2DC                           matlab.ui.control.NumericEditField
        VdivLabel_9                     matlab.ui.control.Label
        ch1DC                           matlab.ui.control.NumericEditField
        VdivLabel_8                     matlab.ui.control.Label
        ch4VA                           matlab.ui.control.DropDown
        ch3VA                           matlab.ui.control.DropDown
        ch2VA                           matlab.ui.control.DropDown
        ch1VA                           matlab.ui.control.DropDown
        Scale_ch4_EditField             matlab.ui.control.NumericEditField
        VdivLabel_4                     matlab.ui.control.Label
        Scale_ch3_EditField             matlab.ui.control.NumericEditField
        VdivLabel_3                     matlab.ui.control.Label
        Scale_ch2_EditField             matlab.ui.control.NumericEditField
        VdivLabel_2                     matlab.ui.control.Label
        Scale_ch1_EditField             matlab.ui.control.NumericEditField
        VdivLabel                       matlab.ui.control.Label
        Channel4CheckBox                matlab.ui.control.CheckBox
        Channel4CheckBoxLabel           matlab.ui.control.Label
        Channel3CheckBox                matlab.ui.control.CheckBox
        Channel3CheckBoxLabel           matlab.ui.control.Label
        Channel2CheckBox                matlab.ui.control.CheckBox
        Channel2CheckBoxLabel           matlab.ui.control.Label
        Channel1CheckBox                matlab.ui.control.CheckBox
        Channel1CheckBoxLabel           matlab.ui.control.Label
        VoltageSuppliesTab              matlab.ui.container.Tab
        InitSupplyButton                matlab.ui.control.Button
        SafeOperatingAreaLabel          matlab.ui.control.Label
        InputVEditField                 matlab.ui.control.EditField
        IDLabel_2                       matlab.ui.control.Label
        MaxIVEditField                  matlab.ui.control.NumericEditField
        PulseDelayusEditField_2Label_10  matlab.ui.control.Label
        InputVoltageLabel               matlab.ui.control.Label
        GateSourceVoltageLabel_2        matlab.ui.control.Label
        DrainSourceVoltageLabel_2       matlab.ui.control.Label
        VdsSourceEditField              matlab.ui.control.EditField
        IDLabel_4                       matlab.ui.control.Label
        VgsSourceEditField              matlab.ui.control.EditField
        IDLabel_3                       matlab.ui.control.Label
        VdsminEditField                 matlab.ui.control.NumericEditField
        PulseDelayusEditField_2Label_9  matlab.ui.control.Label
        VgsminEditField                 matlab.ui.control.NumericEditField
        PulseDelayusEditField_2Label_8  matlab.ui.control.Label
        VdsmaxEditField                 matlab.ui.control.NumericEditField
        PulseDelayusEditField_2Label_7  matlab.ui.control.Label
        VgsmaxEditField                 matlab.ui.control.NumericEditField
        PulseDelayusEditField_2Label_6  matlab.ui.control.Label
        TemperatureManagerTab           matlab.ui.container.Tab
        FeedbackButton                  matlab.ui.control.StateButton
        ReadTemp_EditField              matlab.ui.control.NumericEditField
        SetdTemp_EditField              matlab.ui.control.NumericEditField
        SetTempCLabel_4                 matlab.ui.control.Label
        SetTemp2_EditField              matlab.ui.control.NumericEditField
        SetTempCLabel_3                 matlab.ui.control.Label
        SetTempCLabel_2                 matlab.ui.control.Label
        SetTemp_EditField               matlab.ui.control.NumericEditField
        SetTempCLabel                   matlab.ui.control.Label
        TolTEditField                   matlab.ui.control.NumericEditField
        TolTLabel                       matlab.ui.control.Label
        TempPanel                       matlab.ui.container.Panel
        VoffVEditField                  matlab.ui.control.NumericEditField
        VoffVEditFieldLabel             matlab.ui.control.Label
        VoltageSupplyLabel              matlab.ui.control.Label
        Button                          matlab.ui.control.StateButton
        IDVS_EditField                  matlab.ui.control.EditField
        IDLabel                         matlab.ui.control.Label
        VonVEditField                   matlab.ui.control.NumericEditField
        VonVEditFieldLabel              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        IVFORWARDTRANSFERTab            matlab.ui.container.Tab
        Iteration                       matlab.ui.control.NumericEditField
        IterationNumberLabel            matlab.ui.control.Label
        SourceDropDown_Current          matlab.ui.control.DropDown
        SourceDropDown_Curr             matlab.ui.control.Label
        VDSStepEditField                matlab.ui.control.NumericEditField
        StepVEditField_2Label           matlab.ui.control.Label
        VDS2VEditField                  matlab.ui.control.NumericEditField
        VDS2VEditFieldLabel             matlab.ui.control.Label
        VDS1VEditField                  matlab.ui.control.NumericEditField
        VDS1VEditFieldLabel             matlab.ui.control.Label
        SourceDropDown_VDS              matlab.ui.control.DropDown
        SourceDropDown_Vds              matlab.ui.control.Label
        SourceDropDown_VGS              matlab.ui.control.DropDown
        SourceDropDown_2Label           matlab.ui.control.Label
        PulseWidth_IVF_EditField        matlab.ui.control.NumericEditField
        PulseWidthusEditFieldLabel_2    matlab.ui.control.Label
        VGSStepEditField                matlab.ui.control.NumericEditField
        StepVEditFieldLabel             matlab.ui.control.Label
        VGS2VEditField                  matlab.ui.control.NumericEditField
        VGS2VEditFieldLabel             matlab.ui.control.Label
        DrainSourceVoltageLabel         matlab.ui.control.Label
        GateSourceVoltageLabel_3        matlab.ui.control.Label
        VGS1VEditField                  matlab.ui.control.NumericEditField
        VGS1VEditFieldLabel             matlab.ui.control.Label
        IVFORWARDOUTPUTTab              matlab.ui.container.Tab
        VGS1VEditField_out              matlab.ui.control.NumericEditField
        VGS1VEditField_2Label           matlab.ui.control.Label
        VDS2VEditField_out              matlab.ui.control.NumericEditField
        VDS2VEditField_2Label           matlab.ui.control.Label
        VDS1VEditField_out              matlab.ui.control.NumericEditField
        VDS1VEditField_2Label           matlab.ui.control.Label
        VGSStepEditField_out            matlab.ui.control.NumericEditField
        StepVEditFieldLabel_2           matlab.ui.control.Label
        VGS2VEditField_out              matlab.ui.control.NumericEditField
        VGS2VEditField_2Label           matlab.ui.control.Label
        IncrementEditField              matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        VDSStepEditField_out            matlab.ui.control.NumericEditField
        DrainSourceVoltageLabel_3       matlab.ui.control.Label
        GateSourceVoltageLabel_4        matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        ch4                             matlab.ui.control.UIAxes
        ch3                             matlab.ui.control.UIAxes
        ch2                             matlab.ui.control.UIAxes
        ch1                             matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        % ++++ Mode Selection ++++++++++++++
        % 0 CONFIGuration STM32; 1 SCT; 2 DPT; 3 Capacitor Charge;
        % 4 Capacitor Discahrge; 5 ADC CALibration; 6 IV Curve Trace
        Mode_selected = 0; 
        Test_selected = 1;%  Test: 1 SCT; 2 DPT; 3 IV forward; 4 IV reverse
        % Waiting for the test; 1: Completed; >4: Error
        Mode_status = 0; 

        Timer; % Variabile per il timer
        Timer_status = 0; % Variabile per lo stato del timer
        Timer2; % Variabile per il timer per il controllo della temperatura
        t; % oscilloscope -  time
        
        % Temperature
        BBperiod = 10;
        ftemp;
        plotHandleTemp;
        plotHandleVolt;
        temp_meas = [];
        temp_volt = [];
        temp_time = [];
        temp_index = -1;
        temp_set = 1;

        % App Edits
        logFileName;

        osc_obj % Oscillatore
        OffsetCanali = zeros(1,4) % Salva i valori degli offset (auto zero);
        StatoAutozero = [false, false, false, false];
        flag = 0; % Description
        Stopped = 0; % Description
    end


    methods (Access = private)
        
        function TextEditing (app, TextMessage, logfile)
            newMessage = strcat(string(datetime("now", 'Format', 'dd/MM/yyyy HH:mm:ss | ')), TextMessage);
            app.MessageTextArea.Value =[app.MessageTextArea.Value; newMessage];
            scroll(app.MessageTextArea, 'bottom'); % Scorre automaticamente in basso
            
            if app.FileCheckBox.Value == 1

                % Formattare il messaggio per il file di log con nuova riga
                newMessage = newMessage + newline;
    
                % Aprire il file in modalità append (aggiunge testo senza sovrascrivere)
                fid = fopen(string(logfile), 'a');
                
                % Controllare se il file è stato aperto correttamente
                if fid == -1
                    error('Errore nell''apertura del file.');
                end
    
                % Scrivere all'interno del file
                fprintf(fid, '%s', newMessage);
    
                % Chiudere il file una volta scritto
                fclose(fid);
            end

        end
        
        function cleanupOscilloscopeDisconnection(app)
                try
                    if ~isempty(app.osc_obj) && strcmp(app.osc_obj.Status, 'open')
                        fclose(app.osc_obj);
                        delete(app.osc_obj);
                        app.osc_obj = [];
                    end
                    
                    app.TDS544ALamp.Color = [0.502 0.502 0.502];
                    app.TextEditing('Oscilloscopio disconnesso', app.logFileName);
                catch ME
                    app.TextEditing(sprintf('Errore durante la disconnessione oscilloscopio: %s',ME.message), app.logFileName);
                end
            
        end
       
        function setupTimer(app)
            app.Timer = timer;  
            app.Timer.Period = 0.5; % (s)
            app.Timer.ExecutionMode = 'fixedRate';
            % app.Timer.TimerFcn = @(~,~)CapVoltageButtonValueChanged(app); % Funzione da eseguire
        end

        function plottingData(app, axis , xaxis , yaxis, ytype)
                plot(axis, xaxis, yaxis,'LineWidth',2);
                xlim(axis,[min(xaxis) max(xaxis)]);
                ylim(axis,[min(yaxis) max(yaxis)]);
                if ytype == 'V'
                    ylabel(axis,'Voltage (V)');
                else 
                    ylabel(axis,'Current (A)');
                end
                grid(axis, 'on');
        end

        function plottingData2(app, axis, xaxis1 , yaxis1, xaxis2 , yaxis2, ytype)
                plot(axis, xaxis1, yaxis1, xaxis2, yaxis2, 'LineWidth',2);
                xlim(axis,[min(xaxis1) max(xaxis1)]);
                ylim(axis,[min(yaxis1) max(yaxis1)]);
                if ytype == 'V'
                    ylabel(axis,'Voltage (V)');
                else 
                    ylabel(axis,'Current (A)');
                end
                grid(axis, 'on');
         end
        
        function plottingData3(app, axis , xaxis , yaxis, ytype)
                plot(axis, xaxis, yaxis,'-o','LineWidth',2);
                xlim(axis,[min(xaxis) max(xaxis)]);
                ylim(axis,[min(yaxis) max(yaxis)]);
                if ytype == 'V'
                    ylabel(axis,'Voltage (V)');
                else 
                    ylabel(axis,'Current (A)');
                end
                grid(axis, 'on');
        end 
        
        function Rx_Data = ReceivingInfo(app)
            % stop(app.Timer);
            pause(0.2); % wait for STM32 response
            % [app.CapVoltageVEditField.Value, app.Mode_status] = receiveDataAppHeader(app.PortEditField.Value, app.BaudRateEditField.Value, str2double(app.WordLengthDropDown.Value), str2double(app.StopBitDropDown.Value), app.ParityDropDown.Value, app.TerminatorEditField.Value);
            % NewMessage = (['Capacitor Voltage ', num2str(app.CapVoltageVEditField.Value)]);
            % app.TextEditing(NewMessage, app.logFileName);


            switch app.Mode_status
                case -1
                    Rx_Data = 'Error Time Elapsed: No Data Received';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red
                case 0
                    Rx_Data = 'Sleep Mode: Waiting for a Test';
                    app.Lamp.Color = [1.00,1.00,1.00]; % White
                case 1
                    Rx_Data = 'STM32 SCT/DPT Pulse Configuration Completed';
                    app.Lamp.Color = [0.25 0.940 0.1250]; % Green
                case 2
                    Rx_Data = 'STM32 SCT/DPT Pulse Configuration Failed';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red
                case 10
                    Rx_Data = 'ERROR: Selected Mode does not exist';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red                    
                case 11
                    Rx_Data = 'SCT Completed';
                    app.Lamp.Color = [0.25 0.940 0.1250]; % Green
                case 12
                    Rx_Data = 'SCT Failed';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red
                case 13
                    Rx_Data = 'SCT Aborted: STM32 Configuration';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red
                case 21
                    Rx_Data = 'DPT Completed';
                    app.Lamp.Color = [0.25 0.940 0.1250]; % Green
                case 22
                    Rx_Data = 'DPT Failed';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red
                case 23
                    Rx_Data = 'DPT Aborted: STM32 Configuration';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red
                case 31
                    Rx_Data = 'Capacitor Charge Mode Enabled';
                    app.Lamp.Color = [0.9290 0.6940 0.1250]; % Orange 
                case 32
                    Rx_Data = 'Capacitor Charge Mode Error';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red
                case 41
                    Rx_Data = 'Capacitor Discharge Mode Enabled';
                    app.Lamp.Color = [0.9290 0.6940 0.1250]; % Orange
                case 42
                    Rx_Data = 'Capacitor Discharge Mode Error';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red
                case 51
                    Rx_Data = 'ADC Offset Succesfully Compensated';
                    app.Lamp.Color = [0.25 0.940 0.1250]; % Green
                case 52
                    Rx_Data = 'ADC Calibration Failed';
                    app.Lamp.Color = [0.64,0.08,0.18]; % Red
            end
        end
        
        function SaveAppConfig(app)
            % Inizializza una struttura per memorizzare la configurazione
            config = struct();
            
            % Salva il valore di EditField
            config.EditFieldValue = app.XoffEditField.Value;
            
            % Salva il valore di DropDown
            config.DropDownValue = app.DropDown.Value;
            
            % Salva il testo del Button
            config.ButtonText = app.Button.Text;
            
            % Salva lo stato del CheckBox
            if isprop(app, 'CheckBox')
                config.CheckBoxValue = app.LogCheckBox.Value;
            end
            
            % Salva il valore dello Slider
            if isprop(app, 'Slider')
                config.SliderValue = app.Slider.Value;
            end
            
            % Salva qualsiasi altra variabile personalizzata
            config.CustomVar = app.x;
            
           try
             save('lastAppConfig.mat', 'config');
             disp("Configurazione salvata con successo.");
           catch ME
             warning("Errore nel salvataggio della configurazione: %s", E.message);
           end
        end
     
        % Funzione per la chiusura dell'app (salvare configurazione prima della chiusura)
        
        function LoadAppConfig(app)
            if isfile('lastAppConfig.mat')
                s = load('lastAppConfig.mat', 'config');
                config = s.config;
        
                app.GridLayout.RowHeight = config.GridLayoutRows;
                app.GridLayout.ColumnWidth = config.GridLayoutColumns;
        
                app.Panel.Position = config.PanelPosition;
                app.Panel.BackgroundColor = config.PanelColor;
        
                app.XoffEditField.Value = config.EditFieldValue;
                app.DropDown.Value = config.DropDownValue;
                app.Button.Text = config.ButtonText;
        
                if isprop(app, 'CheckBox')
                    app.LogCheckBox.Value = config.CheckBoxValue;
                end
        
                if isprop(app, 'Slider')
                    app.Slider.Value = config.SliderValue;
                end
        
                app.x = config.CustomVar;
                
                disp("Configurazione caricata.");
            else
                disp("Nessuna configurazione precedente trovata.");
            end
        end
        
        function setupTimerTemp(app)
            app.Timer2 = timer;  
            app.Timer2.Period = app.BBperiod; % (s)
            app.Timer2.BusyMode = 'queue';
            app.Timer2.ExecutionMode = 'fixedRate';
            app.Timer2.TimerFcn = @(~,~)ControllerTempBB(app); % Controllo Bang Bang Temperatura
        end
        
        function ControllerTempBB(app)
            % Inizializza variabili dati se non esistono o sono troppo grandi
            % cap_val = app.CapVoltageButton.Value;
            % if app.CapVoltageButton.Value == 1
            %     app.CapVoltageButton.Value = 0;
            %     CapVoltageButtonValueChanged(app);
            % end


            % maxLen = 10000;
            % if length(app.temp_meas) > maxLen
            %     app.temp_meas = [];
            %     app.temp_volt = [];
            %     app.time = [];
            %     app.temp_index = -1; % Reset indice
            % end

            Tdes = app.SetTemp_EditField.Value;
            app.temp_index = app.temp_index + 1;

            app = get(gcf,'UserData'); % App


            % Temperature Request
            SerialPortParam = SerialPort_Init(app.PortEditField.Value, app.BaudRateEditField.Value, str2double(app.WordLengthDropDown.Value), str2double(app.StopBitDropDown.Value), app.ParityDropDown.Value, app.TerminatorEditField.Value);
            sendDataSTM32(SerialPortParam, 7, 10, app.PulseWidth_IVF_EditField.Value, 100, 120, 0);
            pause(0.02);
            % Temperature Measurement
            [app.temp_meas(end + 1), ~] = receiveDataTempHeader(app.PortEditField.Value, app.BaudRateEditField.Value, str2double(app.WordLengthDropDown.Value), str2double(app.StopBitDropDown.Value), app.ParityDropDown.Value, app.TerminatorEditField.Value, app.Mode_selected);                   
            app.ReadTemp_EditField.Value = app.temp_meas(end);

            % Bang Bang Alghorithm
            if app.temp_meas(end) >= ( Tdes + app.TolTEditField.Value/2) 
                    if isempty(app.temp_volt) ~= 1 && app.temp_volt(end) ~= app.VoffVEditField.Value
                        SupplySetting_HP_Temp(0, app.VoffVEditField.Value, 1);
                        app.temp_volt(end + 1) = app.VoffVEditField.Value;
                    elseif isempty(app.temp_volt)
                        % Applied Voltage
                        SupplySetting_HP_Temp(0, app.VoffVEditField.Value, 1);
                        app.temp_volt(end + 1) = app.VoffVEditField.Value;
                    else
                        app.temp_volt(end + 1) = app.temp_volt(end);
                    end
            elseif app.temp_meas(end) <= ( Tdes - app.TolTEditField.Value/2)
                    if isempty(app.temp_volt) ~= 1 && app.temp_volt(end) ~= app.VonVEditField.Value
                        SupplySetting_HP_Temp(0, app.VonVEditField.Value, 1);
                        app.temp_volt(end + 1) = app.VonVEditField.Value;
                    elseif isempty(app.temp_volt)
                        % Applied Voltage
                        SupplySetting_HP_Temp(0, app.VonVEditField.Value, 1);
                        app.temp_volt(end + 1) = app.VonVEditField.Value;
                    else
                        app.temp_volt(end + 1) = app.temp_volt(end);
                    end
            else
                    app.temp_volt(end + 1) = app.temp_volt(end);
            end

            % Update Time
            app.temp_time(end + 1) = app.temp_index * app.BBperiod;

            % Update Subplot 1: Temperature
            subplot(2,1,1)
            set(app.plotHandleTemp, 'XData', app.temp_time, 'YData', app.temp_meas); hold on;

            % Update Subplot 2: Voltage
            subplot(2,1,2);
            set(app.plotHandleVolt, 'XData', app.temp_time, 'YData', app.temp_volt); 
            %ylim([0, 70]);

            drawnow;

            % if cap_val == 1
            %     app.CapVoltageButton.Value = 1;
            %     CapVoltageButtonValueChanged(app);
            % end

        end


        function isStopped = manageStopFlag(app, f1, FileName, IDmeas, VGSmeas, VDSmeas)
            % Di default assumiamo che non sia stato premuto STOP
            isStopped = false;
            app.Stopped = 0;
            
            % Legge i dati utente dalla figura
            userData = get(f1, 'UserData');
            
            % Controlla se la flag esiste ed è a true
            if isfield(userData, 'stopFlag') && userData.stopFlag
                NewMessage = 'Characterization Aborted';
                disp(NewMessage);
                app.TextEditing(NewMessage, app.logFileName);
                
                % Pop-up Window to save data
                choice = questdlg('Do you save the acquired data?', ...
                                  'Data Saving', ...
                                  'YES', 'NO', 'YES');          
                if strcmp(choice, 'YES')
                    disp('Data Saved');
                    %% save file   
                    save(strcat(FileName,'.mat'), 'IDmeas', 'VGSmeas', 'VDSmeas');
                    set(f1, 'Units', 'normalized', 'OuterPosition', [0 0 1 1]);
                    saveas(f1, strcat(FileName,'.png'));
                end
                close(f1); % Chiude la figura
                
                % Comunica al ciclo principale che deve fermarsi
                isStopped = true; 
                app.Stopped = 1;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Load Previous App Data
            LoadAppConfig(app);

            % Add Path Folders
            addpath('./DSC_Commands');
            addpath('./HP_Commands');
            addpath('./HVSRS_Commands');
            addpath('./Oscilloscope_Commands');
            

            % Timer 1 Configuration
            setupTimer(app);

            % Timer 2 Configuration
            setupTimerTemp(app);

            % Log File Report Creation
            app.logFileName = fullfile(string(app.FolderPathEditField.Value), strcat('TestLogFile_', string(datetime("now", 'Format', 'yyyy_MM_dd_hh_mm_ss')),'.txt'));
            
            % app.IVFRUNButton.Enable = 'off';
            
            app.ZeroCH1.Value = 0;
            app.ZeroCH2.Value = 0;
            app.ZeroCH3.Value = 0;
            app.ZeroCH4.Value = 0;
            app.flag = 0;
            messaggio = 'Attenzione: Prima di avviare le misurazioni effettuare gli auto_zero per i 4 canali';
            titolo = 'Autozero richiesto';

            uialert(app.UIFigure, messaggio, titolo, 'Icon','warning');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                % case "SHORT-CIRCUIT" % SCT
                %     app.Test_selected = 1;
                %     NewMessage = "Test selected: SHORT-CIRCUIT";
                %     app.TextEditing(NewMessage, app.logFileName);
                % case "DOUBLE PULSE" % DPT
                %     app.Test_selected = 2;
                %     % app.PulseWidth1_DPT_EditField.Value= app.Inductance_DPT_EditField.Value * app.CurrentLevel_DPT_EditField.Value / app.OperatingVoltage_DPT_EditField.Value;
                %     NewMessage = "Test selected: DOUBLE PULSE ";
                %     app.TextEditing(NewMessage, app.logFileName);
                case "IV FORWARD TRANSFER" % iv curve
                    app.Test_selected = 3;
                    % app.PulseWidth1_DPT_EditField.Value= app.Inductance_DPT_EditField.Value * app.CurrentLevel_DPT_EditField.Value / app.OperatingVoltage_DPT_EditField.Value;
                    NewMessage = "Test selected: IV TRANSFER CHARACTERIZATION IN FORWARD ";
                    app.TextEditing(NewMessage, app.logFileName);
                case "IV FORWARD OUTPUT" % iv curve
                    app.Test_selected = 4;
                    % app.PulseWidth1_DPT_EditField.Value= app.Inductance_DPT_EditField.Value * app.CurrentLevel_DPT_EditField.Value / app.OperatingVoltage_DPT_EditField.Value;
                    NewMessage = "Test selected: IV OUTPUT CHARACTERIZATION IN FORWARD ";
                    app.TextEditing(NewMessage, app.logFileName);
                otherwise
                    app.Test_selected = -1;
                    NewMessage = "MODE TO DEVELOP";
                    app.TextEditing(NewMessage, app.logFileName);
            end

        end

        % Value changed function: FolderPathEditField
        function FolderPathEditFieldValueChanged(app, event)
            NewMessage = "Folder Path changed";
            app.TextEditing(NewMessage, app.logFileName);
            app.Lamp.Color = [0.9290 0.6940 0.1250]; % Orange
        end

        % Value changed function: FileCheckBox
        function FileCheckBoxValueChanged(app, event)
            if app.FileCheckBox.Value == 1
                NewMessage = "Writing on the file is enabled ";
                app.TextEditing(NewMessage, app.logFileName);
            else
                NewMessage = "Writing on the file is disabled ";
                app.TextEditing(NewMessage, app.logFileName);
            end
        end

        % Button pushed function: GETDATAButton
        function GETDATAButtonPushed(app, event)
          try
            NewMessage = sprintf('Oscilloscope Initializated');
            app.TextEditing(NewMessage, app.logFileName);

            app.t = TDS544A_GetXSingolaConnessione(app.osc_obj);

            % Figures
            % Channel 1
            if (app.Channel1CheckBox.Value == 1)
                y.ch1 = TDS544A_GetDataSingolaConnessione(app.osc_obj, length(app.t), '1', app.XoffEditField.Value, app.AttenuazioneDropDown_CH1.Value, app.OffsetCanali(1));
                app.plottingData(app.ch1,app.t,y.ch1, app.ch1VA.Value);
            end

            % Channel 2
            if (app.Channel2CheckBox.Value == 1)
                y.ch2 = TDS544A_GetDataSingolaConnessione(app.osc_obj, length(app.t), '2', app.XoffEditField.Value, app.AttenuazioneDropDown_CH2.Value, app.OffsetCanali(2));
                app.plottingData(app.ch2,app.t,y.ch2, app.ch2VA.Value);
            end

            % Channel 3
            if (app.Channel3CheckBox.Value == 1)
                y.ch3 = TDS544A_GetDataSingolaConnessione(app.osc_obj, length(app.t), '3', app.XoffEditField.Value, app.AttenuazioneDropDown_CH3.Value, app.OffsetCanali(3));
                app.plottingData(app.ch3,app.t,y.ch3, app.ch3VA.Value);
            end

            % Channel 4
            if (app.Channel4CheckBox.Value == 1)            
                y.ch4 = TDS544A_GetDataSingolaConnessione(app.osc_obj, length(app.t), '4', app.XoffEditField.Value, app.AttenuazioneDropDown_CH4.Value, app.OffsetCanali(4));
                app.plottingData(app.ch4,app.t,y.ch4, app.ch4VA.Value);
            end
        

            FileName = strcat(app.FolderPathEditField.Value, '\', app.DeviceEditField.Value, '_', string(datetime("now", 'Format', 'hh_mm_ss')),'.mat');
            x = app.t;
            save(FileName,'x','y');
          catch ME
            messaggioErrore = sprintf('Errore di Acquisizione: %s', ME.message);
            app.TextEditing(messaggioErrore, app.logFileName);
            uialert(app.UIFigure, messaggioErrore, 'Errore di Comunicazione');
            app.cleanupOscilloscopeDisconnection();
          end
        end

        % Button pushed function: STM32ConfigButton
        function STM32ConfigButtonPushed(app, event)
            timer_flag = app.Timer_status;
            %app.CapVoltageButton.Value = 0;
            %CapVoltageButtonValueChanged(app);
            pause(0.5);
            app.Mode_selected = 0;
            NewMessage = 'STM32 Configuration Request';
            app.TextEditing(NewMessage, app.logFileName);
            SerialPortParam = SerialPort_Init(app.PortEditField.Value, app.BaudRateEditField.Value, str2double(app.WordLengthDropDown.Value), str2double(app.StopBitDropDown.Value), app.ParityDropDown.Value, app.TerminatorEditField.Value);

            
            % if app.Test_selected == 1 % SCT
            % 
            %     % Send Data to STM32F401 to run the test
            %     sendDataSTM32(SerialPortParam, app.Mode_selected, 0, 0, 100, 120, 0);
            % 
            % elseif app.Test_selected == 2 % DPT
            % 
            %     % Send Data to STM32F401 to run the test
            %     sendDataSTM32(SerialPortParam, app.Mode_selected, app.PulseDelay1_DPT_EditField.Value, app.PulseWidth1_DPT_EditField.Value, app.PulseDelay2_DPT_EditField.Value, app.PulseWidth2_DPT_EditField.Value, app.OperatingVoltage_DPT_EditField.Value);
            % end
            
            NewMessage = app.ReceivingInfo;
            app.TextEditing(NewMessage, app.logFileName); 
            
           pause(0.5);
           % if timer_flag == 0
           %     app.CapVoltageButton.Value = 0;
           %      CapVoltageButtonValueChanged(app);
           % else
           %     app.CapVoltageButton.Value = 1;
           %     CapVoltageButtonValueChanged(app);
           % end

        end

        % Button pushed function: CONFIGXButton
        function CONFIGXButtonPushed(app, event)
          try
            app.t = TDS544A_XSingolaConnessione(app.osc_obj, app.RecordLengthDropDown.Value, app.Time_EditField.Value,app.ModeDropDown.Value, app.XoffEditField.Value, app.TrigPos_EditField.Value, app.SourceDropDown_TRG.Value,app.TrigLevel_EditField.Value, app.SlopeDropDown.Value);
            app.TextEditing('Configurazione asse X completata con successo',app.logFileName);
            pause(0.5);
          catch ME 
            app.cleanupOscilloscopeDisconnection();
            messaggioErroreX = sprintf('Errore in CONFIG X: %s', ME.message);
            app.TextEditing(messaggioErroreX, app.logFileName);
            uialert(app.UIFigure, messaggioErroreX, 'Errore in CONFIG X');
          end
         
        end

        % Button pushed function: CONFIGYButton
        function CONFIGYButtonPushed(app, event)
          try
            % Channel 1
            if (app.Channel1CheckBox.Value == 1)
                TDS544A_YSingolaConnessione(app.osc_obj, 'CH1', app.Scale_ch1_EditField.Value, app.ch1DC.Value);
            end

            % Channel 2
            if (app.Channel2CheckBox.Value == 1)
                TDS544A_YSingolaConnessione(app.osc_obj, 'CH2', app.Scale_ch2_EditField.Value, app.ch2DC.Value);
            end

            % Channel 3
            if (app.Channel3CheckBox.Value == 1)
                 TDS544A_YSingolaConnessione(app.osc_obj, 'CH3', app.Scale_ch3_EditField.Value, app.ch3DC.Value);
            end

            % Channel 4
            if (app.Channel4CheckBox.Value == 1)            
                 TDS544A_YSingolaConnessione(app.osc_obj, 'CH4', app.Scale_ch4_EditField.Value, app.ch4DC.Value);
            end        
            app.TextEditing('Configurazione asse Y completata con successo',app.logFileName);
            pause(0.5);
          catch ME 
            app.cleanupOscilloscopeDisconnection();
            messaggioErroreY = sprintf('Errore in CONFIG Y: %s', ME.message);
            app.TextEditing(messaggioErroreY, app.logFileName);
            uialert(app.UIFigure, messaggioErroreY, 'Errore in CONFIG Y');
          end
        end

        % Value changed function: Time_EditField
        function Time_EditFieldValueChanged(app, event)
            app.TimeWind_EditField.Value = app.Time_EditField.Value * 10;
            NewMessage = 'Pulse Width Changed';
            app.TextEditing(NewMessage, app.logFileName);
        end

        % Button pushed function: IVFRUNButton
        function IVFRUNButtonPushed(app, event)
         try
            app.Stopped = 0;
            ConnectTDS544AButtonPushed(app, []);
            app.Mode_selected = 6;
            IVFTransferButton = 0;
            IVFOutputButton = 0;
            TempButton = 0;
            close(figure(1));
            current_yscale_init = 35;
            int_comp_iter_limit = app.Comp_iterEditField.Value; % Iteration Limit for Multiple Compensations.
            SerialPortParam = SerialPort_Init(app.PortEditField.Value, app.BaudRateEditField.Value, str2double(app.WordLengthDropDown.Value), str2double(app.StopBitDropDown.Value), app.ParityDropDown.Value, app.TerminatorEditField.Value);
            supply = SupplyOpenCom_HP;
            
            switch (str2double(app.SourceDropDownType.Value))
                case(1)
                    IVFTransferButton = 1;
                case(2)
                    IVFOutputButton = 1;
                case(3)
                    IVFTransferButton = 1;
                    IVFOutputButton = 1;
                case(4)
                    IVFTransferButton = 1;
                    TempButton = 1;
                case(5)
                    IVFTransferButton = 1;
                    TempButton = 1;
                case(6)
                    IVFTransferButton = 1;
                    IVFOutputButton = 1;
                    TempButton = 1;
            end

            app.Time_EditField.Value = (app.PulseWidth_IVF_EditField.Value * 0.12 + 0.2) * 1e-6;

            app.CONFIGXButtonPushed(app);
            app.CONFIGYButtonPushed(app);

            app.Lamp.Color = [0.9290 0.6940 0.1250]; % Orange

            % Transfer characteristic tracing
            if IVFTransferButton == 1
                i_plot=0;
                VGS = app.VGS1VEditField.Value:app.VGSStepEditField.Value:app.VGS2VEditField.Value;
                if app.VDSStepEditField.Value == 0
                    VDS = app.VDS1VEditField.Value;
                else
                    VDS = app.VDS1VEditField.Value:app.VDSStepEditField.Value:app.VDS2VEditField.Value;
                end
                VGSdes_plot = VGS;
                VDSdes_plot = VDS;

                if IVFOutputButton == 1
                    VDS2 = app.VDS1VEditField_out.Value:app.VDSStepEditField_out.Value:app.VDS2VEditField_out.Value;
                    if app.VGSStepEditField_out.Value == 0
                        VGS2 = app.VGS1VEditField_out.Value;
                    else
                        VGS2 = app.VGS1VEditField_out.Value:app.VGSStepEditField_out.Value:app.VGS2VEditField_out.Value;
                    end
                else
                    VDS2 = 0; VGS2 = 0;
                end
                
                vg_init = max([VGS VGS2]); vd_init = max([VDS VDS2]);
                TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value, current_yscale_init, -3.8);
                TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VGS.Value, vg_init/4, -1.5);
                TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, vd_init/2, 0.5);
   
                SupplySetting_HP(supply, vg_init,vd_init, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 1);
                pause(1);
                sendDataSTM32(SerialPortParam, app.Mode_selected, 10, app.PulseWidth_IVF_EditField.Value, 100, 120, max(VDS));
                    pause(0.1);
                SupplySetting_HP(supply, vg_init,vd_init, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 0);
                clear VGS2 VDS2 vg_init vd_init;
                %% Selecting Measurement Time Interval
                current_sel = extractAfter(app.SourceDropDown_Current.Value, 'CH');
                % canale = str2double(app.SourceDropDown_Current.Value);
                Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                figure(1)
                plot(app.t,y);
                hold on; grid on
                current_sel = extractAfter(app.SourceDropDown_TRG.Value, 'CH');
                Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                plot(app.t,y);
                legend('Drain Current','HST Gate Voltage','Location','southeast','FontSize',16);
                xlabel('Time (s)','FontSize',16);
                % Pop-up Window to confirm the script execution
                choice = questdlg('Do you want to proceed with?', ...
                                  'Parameters Set Check', ...
                                  'OK', 'NO', 'OK');          
                switch choice
                    case 'OK'
                        disp('Characterization Started');
            
                    case 'NO'
                        close(figure(1));
                        SupplyCloseCom_HP(supply);
                        NewMessage = 'Characterization Aborted';
                        disp(NewMessage);
                        app.TextEditing(NewMessage, app.logFileName);
                        return  
                end
                
                choiceg = questdlg('Come vuoi selezionare l''intervallo di tempo?', ...
                  'Inizio Caratterizzazione', ...
                  'Graficamente', 'Manualmente', 'Annulla', 'Graficamente');          

                switch choiceg
                    case 'Graficamente'
                        disp('Characterization Started - Modalità Grafica');
                        % L'utente clicca 2 volte sul grafico
                        [time, ~] = ginput(2);
                        time = sort(time); % Riordina per sicurezza se clicca da destra a sinistra
                
                    case 'Manualmente'
                        disp('Characterization Started - Modalità Manuale');
                        % Popup di testo
                        prompt = {'Inserisci t1 (inizio in secondi):', 'Inserisci t2 (fine in secondi):'};
                        dlgtitle = 'Selezione Intervallo di Tempo';
                        dims = [1 55];
                        definput = {num2str(0.6e-04), num2str(0.7e-04)}; 
                        answer = inputdlg(prompt, dlgtitle, dims, definput);
                        
                        if isempty(answer)
                            % Se chiude il popup di testo
                            close(figure(1));
                            SupplyCloseCom_HP(supply);
                            NewMessage = 'Characterization Aborted';
                            disp(NewMessage);
                            app.TextEditing(NewMessage, app.logFileName);
                            return;
                        end
                        
                        time(1) = str2double(answer{1});
                        time(2) = str2double(answer{2});
                        time = sort(time);
                
                    case 'Annulla'
                        % Annulla tutto
                        close(figure(1));
                        SupplyCloseCom_HP(supply);
                        NewMessage = 'Characterization Aborted';
                        disp(NewMessage);
                        app.TextEditing(NewMessage, app.logFileName);
                        return;
                        
                    otherwise
                        % Se l'utente chiude la finestra premendo la X rossa
                        close(figure(1));
                        SupplyCloseCom_HP(supply);
                        NewMessage = 'Characterization Aborted';
                        disp(NewMessage);
                        app.TextEditing(NewMessage, app.logFileName);
                        return;
                end                        
                        tmeas = app.t((time(1) < app.t) & (app.t < time(2)));
                        close(figure(1));

                FileName = strcat(app.FolderPathEditField.Value, '\', app.DeviceEditField.Value, '_TranIV_', num2str(app.SetTemp_EditField.Value),'_', string(datetime("now", 'Format', 'hh_mm_ss')));                
                VDSmeas=zeros(length(VDS),length(VGS));
                IDmeas=zeros(length(VDS),length(VGS));
                VGSmeas=zeros(length(VDS),length(VGS));

                %% Probe Offset Calculating due to the circuit
                if app.VCompButton_2.Value == 1
                    SupplySetting_HP(supply, max(VGS), 0, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 1);
                    % TDS544A_Degauss_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value);
                    % pause(1);
                    sendDataSTM32(SerialPortParam, app.Mode_selected, 10, app.PulseWidth_IVF_EditField.Value, 100, 120, max(VDS));
                    pause(0.1);
                    %Setting per capire se c'è un offset riguardante il
                    %circuito
                    SupplySetting_HP(supply, max(VGS), 0, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 0);

                    current_sel = extractAfter(app.SourceDropDown_VDS.Value, 'CH');
                    % canale = str2double(current_sel);
                    Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                    y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));                            
                    %Offset riguardo il circuito                   
                    calib_Vds = mean(y((time(1)<app.t)&(app.t<time(2)))); % Offset Vds Probe    
                else  
                    calib_Vds = 0;
                end
                   

                for i_vds=1:length(VDS)
                    VDSdes = VDS(i_vds) + calib_Vds;
                    % TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, VDSdes/4, -2);
                    % Zoom(calib_Vds, app.osc_obj, app.SourceDropDown_VDS.Value);
                    deltaVd = 0;

                    % Figure with Button to stop the characterization
                    f1=figure(1);
                    subplot(2,1,1);
                    subplot(2,1,2);
                    % Flag Initialization
                    set(f1, 'UserData', struct('stopFlag', false));
                    % STOP Button
                    uicontrol('Style', 'pushbutton', 'String', 'STOP','FontSize', 12, ...
                        'FontWeight', 'bold', 'ForegroundColor', 'w','BackgroundColor', [1 0 0],...
                        'Position', [30 10 80 20], 'Callback', @(src, event) set(f1, 'UserData', struct('stopFlag', true)));


                    for i_vgs=1:length(VGS)
                        VGSdes = VGSdes_plot(i_vgs); % Desired Gate Voltage
                        
                        % vg_init = max(VGS); vd_init = max(VDS);
                        % TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VGS.Value, vg_init/4, -1.5);
                        % TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, vd_init/2, 0.5);
                        % TDS544A_Y_OffsetSingolaConnessione(app.osc_obj, app.SourceDropDown_VGS.Value, VGSdes/4, -1.5, VGSdes, app.AttenuazioneDropDown_CH1.Value, 0);

                        %% Probe Offset Calculating due to the circuit
                        % if app.VCompButton_2.Value == 1
                        %    TDS544A_Y_OffsetSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, 0.05, -1, 0, app.AttenuazioneDropDown_CH2.Value, 0);
                        % 
                        %     SupplySetting_HP(supply, VGSdes, 0, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 1);
                        %     % TDS544A_Degauss_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value);
                        %     % pause(1);
                        %     sendDataSTM32(SerialPortParam, app.Mode_selected, 10, app.PulseWidth_IVF_EditField.Value, 100, 120, max(VDS));
                        %     pause(0.1);
                        %     %Setting per capire se c'è un offset riguardante il
                        %     %circuito
                        %     SupplySetting_HP(supply, VGSdes, 0, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 0);
                        % 
                        %     current_sel = extractAfter(app.SourceDropDown_VDS.Value, 'CH');
                        %     % canale = str2double(current_sel);
                        %     Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                        %     y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));                            
                        %     %Offset riguardo il circuito                   
                        %     calib_Vds = mean(y((time(1)<app.t)&(app.t<time(2)))); % Offset Vds Probe    
                        % else  
                        %     calib_Vds = 0;
                        % end
                        % VDSdes = VDS(i_vds) + calib_Vds; % Desired Drain Voltage
                        current_yscale = 2.5;
                        TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, current_yscale, -3.6);                    
                        
                        
                        if i_vgs > 1
                            % Flag Checking
                            drawnow; 
                            
                            if app.manageStopFlag(f1, FileName, IDmeas, VGSmeas, VDSmeas)
                                return;
                            end
                            
                        end
                        
                        current_yscale = current_yscale_init;
                        TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value, current_yscale, -3.8);
                        % TDS544A_Y_OffsetSingolaConnessione(app.osc_obj, app.SourceDropDown_VGS.Value, 0.5, 0.5, VGSdes, 1);
                        Zoom(VGSdes, app.osc_obj, app.SourceDropDown_VGS.Value, -1);

                        % Compensation Algorithm
                        if app.VCompButton.Value == 1
                            deltaVg = 0;
                            int_comp_count = 1; % Iterations for multiple checks                            
                            for int_comp_count = 1:int_comp_iter_limit
                                 current_yscale = 2.5;
                                 TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, current_yscale, -3.6);                    
                                 current_yscale = current_yscale_init;
                                 TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value, current_yscale, -3.8);
                                 Zoom(VGSdes, app.osc_obj, app.SourceDropDown_VGS.Value, -1);

                                 NewMessage = 'Compensating Gate-Source Voltage';
                                 app.TextEditing(NewMessage, app.logFileName);
                                 [VGS(i_vgs), VGSoffset] = TDS544ACompVoltage_HP_Gate(supply, VGS(i_vgs), VGSdes+deltaVg, VDSdes, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 0.05, app.KpEditField.Value, app.KiEditField.Value, app.osc_obj, app.t, app.SourceDropDown_VGS.Value, time(1), time(2), ...
                                     SerialPortParam,  app.Mode_selected, app.PulseWidth_IVF_EditField.Value, app.XoffEditField.Value, app.AttenuazioneDropDown_CH1.Value, app.AttenuazioneDropDown_CH2.Value, app.AttenuazioneDropDown_CH3.Value, app.AttenuazioneDropDown_CH4.Value, app.OffsetCanali);
                                 
                                 if app.manageStopFlag(f1, FileName, IDmeas, VGSmeas, VDSmeas)
                                    return; % Termina completamente il RUN
                                 end
                                 
                                 NewMessage = ['VGS Compensation Factor: ', num2str(VGS(i_vgs) - VGSdes), ' V'];
                                 app.TextEditing(NewMessage, app.logFileName);
    
                                 NewMessage = 'Compensating Drain-Source Voltage';
                                 app.TextEditing(NewMessage, app.logFileName);
                                 [VDS(i_vds),current_yoffset, VDSoffset] = TDS544ACompVoltage_HP(supply, VGS(i_vgs), VDSdes, VDSdes+deltaVd, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, app.TolVEditField.Value, app.KpEditField.Value, app.KiEditField.Value, app.osc_obj, app.t, app.SourceDropDown_VDS.Value, app.SourceDropDown_Current.Value, time(1), time(2), ...
                                     SerialPortParam,  app.Mode_selected, app.PulseWidth_IVF_EditField.Value, app.XoffEditField.Value, app.AttenuazioneDropDown_CH1.Value, app.AttenuazioneDropDown_CH2.Value, app.AttenuazioneDropDown_CH3.Value, app.AttenuazioneDropDown_CH4.Value, app.OffsetCanali);
                                 
                                 if app.manageStopFlag(f1, FileName, IDmeas, VGSmeas, VDSmeas)
                                    return; % Termina completamente il RUN
                                 end

                                 NewMessage = ['VDS Compensation Factor: ', num2str(VDS(i_vds) - VDSdes), ' V'];
                                 app.TextEditing(NewMessage, app.logFileName);
                                 deltaVd = VDS(i_vds) - VDSdes;
    
                                 % Vgs check
                                 current_sel = extractAfter(app.SourceDropDown_VGS.Value, 'CH');
                                 % canale = str2double(current_sel);
                                 Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                                 y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                                 deltaVg = VGSdes - mean(y((time(1)<app.t)&(app.t<time(2))));
                                 if (abs(deltaVg) < app.TolVEditField.Value)||((VGS(i_vgs) + deltaVg > app.VgsmaxEditField.Value) &&  int_comp_count > 2)
                                     break;
                                 end
                            end
                        else
                             Vtol = 50;
                             [VDS(i_vds),current_yoffset, VDSoffset] = TDS544ACompVoltage_HP(supply, VGS(i_vgs), VDSdes, VDSdes+deltaVd, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, app.TolVEditField.Value, app.KpEditField.Value, app.KiEditField.Value, app.osc_obj, app.t, app.SourceDropDown_VDS.Value, app.SourceDropDown_Current.Value, time(1), time(2), ...
                                 SerialPortParam,  app.Mode_selected, app.PulseWidth_IVF_EditField.Value, app.XoffEditField.Value, app.AttenuazioneDropDown_CH1.Value, app.AttenuazioneDropDown_CH2.Value, app.AttenuazioneDropDown_CH3.Value, app.AttenuazioneDropDown_CH4.Value, app.OffsetCanali);
                             
                             if app.manageStopFlag(f1, FileName, IDmeas, VGSmeas, VDSmeas)
                                    return; % Termina completamente il RUN
                             end
                        
                        end
                                
                        if app.VdsmaxEditField.Value >= VDS(i_vds)
            
                            % Oscilloscope Current Scale Configuration
                            current_sel = extractAfter(app.SourceDropDown_Current.Value, 'CH');
                            Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                            y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                            y = y((time(1)<app.t)&(app.t<time(2)));
                            Izoom =[mean(y) , max(y), min(y)];
                            TDS544A_Y_OffsetSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value,  abs((Izoom(2) - Izoom(3))/(str2double(app.(Attenuazione).Value)))/2, 0, Izoom(1), app.(Attenuazione).Value, 0);
                         % Zoom((current_yoffset/str2double(app.AttenuazioneDropDown_CH3.Value)), app.osc_obj, app.SourceDropDown_Current.Value, -3);
              
                            for i = 1:app.Iteration.Value
    
                                SupplySetting_HP(supply, VGS(i_vgs), VDS(i_vds), app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 1);
                                sendDataSTM32(SerialPortParam, app.Mode_selected, 10, app.PulseWidth_IVF_EditField.Value, 100, 120, VDSdes);
                                pause(0.1)
                                % SupplySetting_HP(supply, VGS(i_vgs), VDS(i_vds), app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 0);
    
                                %% Gate Voltage
                                current_sel = extractAfter(app.SourceDropDown_VGS.Value, 'CH');
                                % canale = str2double(current_sel);
                                Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                                y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                                VGSmeas(i_vds,i_vgs) = mean(y((time(1)<app.t)&(app.t<time(2)))) + VGSmeas(i_vds,i_vgs);
                                app.plottingData2(app.ch1,app.t,y,tmeas,y((time(1)<app.t)&(app.t<time(2))), 'V');
                        
                                %% Drain Current
                                current_sel = extractAfter(app.SourceDropDown_Current.Value, 'CH');
                                % canale = str2double(current_sel);
                                Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                                y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                                IDmeas(i_vds,i_vgs) = mean(y((time(1)<app.t)&(app.t<time(2)))) + IDmeas(i_vds,i_vgs);
                                app.plottingData2(app.ch3,app.t,y,tmeas,y((time(1)<app.t)&(app.t<time(2))), 'A');               
		                                                   
                                %% Drain Voltage
                                current_sel = extractAfter(app.SourceDropDown_VDS.Value, 'CH');
                                % canale = str2double(current_sel);
                                Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                                y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));                            
                                VDSmeas(i_vds,i_vgs) = mean(y((time(1)<app.t)&(app.t<time(2)))) + VDSmeas(i_vds,i_vgs);
                                app.plottingData2(app.ch2,app.t,y,tmeas,y((time(1)<app.t)&(app.t<time(2))), 'V');
                                
        
                            end
                        
                            VGSmeas(i_vds,i_vgs) = VGSmeas(i_vds,i_vgs)/app.Iteration.Value;
                            IDmeas(i_vds,i_vgs) = IDmeas(i_vds,i_vgs)/app.Iteration.Value;
                            VDSmeas(i_vds,i_vgs) = VDSmeas(i_vds,i_vgs)/app.Iteration.Value - calib_Vds;
    
                            NewMessage = ['Measurement Point: VGS ',num2str(VGS(i_vgs)), ' V and VDS', num2str(VDS(i_vds)), ' V'];
                            app.TextEditing(NewMessage, app.logFileName);

                            x = VGSmeas(i_vds,:);
                            y1 = IDmeas(i_vds,:);
                            y2 = VDSmeas(i_vds,:);
                            subplot(2,1,1);
                            plot(x(x ~= 0),y1(y1 ~= 0),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('Drain Current (A)','FontSize',20);xlabel('Gate Voltage (V)','FontSize',20);grid on;lgd=legend(cellstr(num2str(VDSdes_plot(i_vds)')),'FontSize',10,'Location','best','LineWidth',1);title(lgd,'Drain Voltage(V)');
                            subplot(2,1,2);
                            plot(x(x ~= 0),y2(y2 ~= 0),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('Drain Voltage (V)','FontSize',20);xlabel('Gate Voltage (V)','FontSize',20);grid on;
                        else
                            break;
                        end                
                    end
                    close(figure(1));
                    if i_plot > 0
                        close(figure(2));
                    end
                    % f2 = figure(2);       
                    % for i_plot=1:i_vds
                    %     subplot(2,1,1);
                    %     title(strcat('TRANSFER CURVE - DEV: ', app.DeviceEditField.Value),'FontSize',24);
                    %     index_lim = sum(VGSmeas(i_plot, :) ~= 0);
                    %     plot(VGSmeas(i_plot,1:index_lim),IDmeas(i_plot,1:index_lim),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('Drain Current (A)','FontSize',20);xlabel('Gate Voltage (V)','FontSize',20);grid on;
                    %     hold on;
                    %     subplot(2,1,2);
                    %     plot(VGSmeas(i_plot,1:index_lim),VDSmeas(i_plot,1:index_lim)-VDSdes_plot(i_plot),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('V_{DS-meas} - V_{DS-des} (V)','FontSize',20);xlabel('Gate Voltage (V)','FontSize',20);grid on;lgd=legend(cellstr(num2str(VDSdes_plot')),'FontSize',16,'Location','eastoutside','LineWidth',2);title(lgd,'Drain Voltage(V)');
                    %     hold on;
                    % end

                    f2 = figure(2);       
                    for i_plot=1:i_vds
                        subplot(2,1,1);
                        title(strcat('TRANSFER CURVE - DEV: ', app.DeviceEditField.Value),'FontSize',24);
                        index_lim = sum(VGSmeas(i_plot, :) ~= 0);
                        plot(VGSmeas(i_plot,1:index_lim),IDmeas(i_plot,1:index_lim),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('Drain Current (A)','FontSize',20);xlabel('Gate Voltage (V)','FontSize',20);grid on;
                        hold on;
                        
                        subplot(2,1,2);
                        plot(VGSmeas(i_plot,1:index_lim),VDSmeas(i_plot,1:index_lim)-VDSdes_plot(i_plot),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('V_{DS-meas} - V_{DS-des} (V)','FontSize',20);xlabel('Gate Voltage (V)','FontSize',20);grid on;
                        hold on;
                    end
                    subplot(2,1,2);
                    lgd=legend(cellstr(num2str(VDSdes_plot')),'FontSize',16,'Location','eastoutside','LineWidth',2);
                    title(lgd,'Drain Voltage(V)');

                    yval = 10*app.TolVEditField.Value;
                    ylim([-yval yval]);
                    xlim()
                    x_fill = [min(VGSmeas(:)) max(VGSmeas(:)) max(VGSmeas(:)) min(VGSmeas(:))];
                    y_fill = [-(app.TolVEditField.Value) -(app.TolVEditField.Value) app.TolVEditField.Value app.TolVEditField.Value];
                    fill(x_fill, y_fill, 'r', 'FaceAlpha', 0.3, 'EdgeColor', 'none','HandleVisibility', 'off');  % fascia rossa semitrasparente
                    text(0.5, 0.1, ['Tolerance = ', num2str(app.TolVEditField.Value), ' V'], 'Units', 'normalized', 'HorizontalAlignment', 'center', 'FontSize', 18, 'Color', 'r')
                    text(0.98, 0.05, [num2str(app.PulseWidth_IVF_EditField.Value), 'us'], 'Units', 'normalized', 'HorizontalAlignment', 'right', 'VerticalAlignment','bottom', 'FontSize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'white', 'EdgeColor', 'black');
                    %% save options                
                    save(strcat(FileName,'.mat'), 'IDmeas', 'VGSmeas', 'VDSmeas');
                    set(f2, 'Units', 'normalized', 'OuterPosition', [0 0 1 1]);
                    saveas(f2, strcat(FileName,'.png'));
                end

                NewMessage = 'Transfer IV Completed';
                app.TextEditing(NewMessage, app.logFileName);
                app.Lamp.Color = [0.25 0.940 0.1250]; % Green

            end

            % Output characteristic tracing
            if IVFOutputButton == 1
                i_plot = 0;
                switch (app.DropDown.Value)
                case(1)
                    % Linear Increment
                    VDS = app.VDS1VEditField_out.Value:app.VDSStepEditField_out.Value:app.VDS2VEditField_out.Value;
                case(2)
                    % Logarithmic Array
                    VDS = log_spacing(app.VDSStepEditField_out.Value, app.VDS2VEditField_out.Value);
                end

                if app.VGSStepEditField_out.Value == 0
                    VGS = app.VGS1VEditField_out.Value;
                else
                    VGS = app.VGS1VEditField_out.Value:app.VGSStepEditField_out.Value:app.VGS2VEditField_out.Value;
                end
                VDSdes_plot = VDS;
                VGSdes_plot = VGS;
                current_yscale = current_yscale_init;
                TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value, current_yscale_init, -4.5);
                TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VGS.Value, max(VGS)/4, -3);
                TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, max(VDS)/6, -3);

                if IVFTransferButton == 0     
                    %% Selecting Measurement Time Interval
                    SupplySetting_HP(supply, max(VGS),max(VDS), app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 1);
                    pause(1);
                    sendDataSTM32(SerialPortParam, app.Mode_selected, 10, app.PulseWidth_IVF_EditField.Value, 100, 120, max(VDS));
                    pause(0.1);
                    SupplySetting_HP(supply, max(VGS),max(VDS), app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 0);

                    current_sel = extractAfter(app.SourceDropDown_Current.Value, 'CH');
                    % canale = str2double(current_sel);
                    Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                    y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                    figure(1)
                    plot(app.t,y);
                    hold on; grid on
                    current_sel = extractAfter(app.SourceDropDown_TRG.Value, 'CH');
                    % canale = str2double(current_sel);
                    Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                    y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                    plot(app.t,y);
                    legend('Drain Current','HST Gate Voltage','Location','southeast','FontSize',16);
                    xlabel('Time (s)','FontSize',16);
                    % Pop-up Window to confirm the script execution
                    choice = questdlg('Do you want to proceed with?', ...
                                      'Parameters Set Check', ...
                                      'OK', 'NO', 'OK');          
                    switch choice
                        case 'OK'
                            disp('Characterization Started');
                
                        case 'NO'
                            close(figure(1));
                            SupplyCloseCom_HP(supply);
                            NewMessage = 'Characterization Aborted';
                            disp(NewMessage);
                            app.TextEditing(NewMessage, app.logFileName);
                            return  
                    end
                choiceg = questdlg('Come vuoi selezionare l''intervallo di tempo?', ...
                'Inizio Caratterizzazione', ...
                'Graficamente', 'Manualmente', 'Annulla', 'Graficamente');          

                switch choiceg
                    case 'Graficamente'
                        disp('Characterization Started - Modalità Grafica');
                        % L'utente clicca 2 volte sul grafico
                        [time, ~] = ginput(2);
                        time = sort(time); % Riordina per sicurezza se clicca da destra a sinistra
                
                    case 'Manualmente'
                        disp('Characterization Started - Modalità Manuale');
                        % Popup di testo
                        prompt = {'Inserisci t1 (inizio in secondi):', 'Inserisci t2 (fine in secondi):'};
                        dlgtitle = 'Selezione Intervallo di Tempo';
                        dims = [1 55];
                        definput = {num2str(0.6e-04), num2str(0.7e-04)}; 
                        answer = inputdlg(prompt, dlgtitle, dims, definput);
                        
                        if isempty(answer)
                            % Se chiude il popup di testo
                            close(figure(1));
                            SupplyCloseCom_HP(supply);
                            NewMessage = 'Characterization Aborted';
                            disp(NewMessage);
                            app.TextEditing(NewMessage, app.logFileName);
                            return;
                        end
                        
                        time(1) = str2double(answer{1});
                        time(2) = str2double(answer{2});
                        time = sort(time);
                
                    case 'Annulla'
                        % Annulla tutto
                        close(figure(1));
                        SupplyCloseCom_HP(supply);
                        NewMessage = 'Characterization Aborted';
                        disp(NewMessage);
                        app.TextEditing(NewMessage, app.logFileName);
                        return;
                        
                    otherwise
                        % Se l'utente chiude la finestra premendo la X rossa
                        close(figure(1));
                        SupplyCloseCom_HP(supply);
                        NewMessage = 'Characterization Aborted';
                        disp(NewMessage);
                        app.TextEditing(NewMessage, app.logFileName);
                        return;
                end                        
                        tmeas = app.t((time(1) < app.t) & (app.t < time(2)));
                        close(figure(1));

                end

                FileName = strcat(app.FolderPathEditField.Value, '\', app.DeviceEditField.Value, '_OutIV_', num2str(app.SetTemp_EditField.Value), '_', string(datetime("now", 'Format', 'hh_mm_ss')));                
                VDSmeas=zeros(length(VDS),length(VGS));
                IDmeas=zeros(length(VDS),length(VGS));
                VGSmeas=zeros(length(VDS),length(VGS));
                
                deltaVd = 0;

                TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value, current_yscale, -4);

                for i_vgs=1:length(VGS)
                    VGSdes = VGSdes_plot(i_vgs); % Desired Gate Voltage

                    TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, 0.1, 0);
                    current_yscale = current_yscale_init;
                    TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value, current_yscale, -3.8);
                    % Zoom(, app.osc_obj, app.SourceDropDown_VGS.Value, -1);

                    %% Probe Offset Calculating
                    if app.VCompButton_2.Value == 1
                        %Abilito il supply
                        SupplySetting_HP(supply, VGS(i_vgs), 0, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 1);
                        % TDS544A_Degauss_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value);
                        % pause(1);
                        sendDataSTM32(SerialPortParam, app.Mode_selected, 10, app.PulseWidth_IVF_EditField.Value, 100, 120, max(VDS));
                        pause(0.1);
                        %Disabilito il supply
                        SupplySetting_HP(supply, VGS(i_vgs), 0, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 0);
        
                        current_sel = extractAfter(app.SourceDropDown_VDS.Value, 'CH');
                        % canale = str2double(current_sel);
                        Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                        y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));                            
                        calib_Vds = mean(y((time(1)<app.t)&(app.t<time(2)))); % Offset Vds Probe
                    else
                        calib_Vds = 0;
                    end
                    % if calib_Vds > 0 
                    %     calib_Vds = 0; 
                    % end 
                    deltaVd = 0;
                    
                    % Figure with Button to stop the characterization
                    f1=figure(1);
                    subplot(2,1,1);
                    subplot(2,1,2);
                    % Flag Initialization
                    set(f1, 'UserData', struct('stopFlag', false));
                    % STOP Button
                    uicontrol('Style', 'pushbutton', 'String', 'STOP','FontSize', 12, ...
                        'FontWeight', 'bold', 'ForegroundColor', 'w','BackgroundColor', [1 0 0],...
                        'Position', [30 10 80 20], 'Callback', @(src, event) set(f1, 'UserData', struct('stopFlag', true)));
                    
                    for i_vds=1:length(VDS)
                            TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VGS.Value, max(VGS)/4, -3);
                            current_yscale = 2.5;
                            TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, current_yscale, -3.6); 
                            current_yscale = current_yscale_init;
                            TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value, current_yscale, -3.8); 
                        if i_vds > 1
                            deltaVd = VDS(i_vds-1) - VDSdes; % Forward Control based on the previous error
                            % current_sel = extractAfter(app.SourceDropDown_VDS.Value, 'CH');
                            % Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                            % TDS544A_Y_OffsetSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, abs(VDS(i_vds)/(str2double(app.(Attenuazione).Value)))/4, -1, VDS(i_vds), app.(Attenuazione).Value, 0);
                            % TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, VDSdes/2, -3);  
                            % Zoom(VDSdes, app.osc_obj, app.SourceDropDown_VDS.Value, -2);
                            
                            % Flag Checking
                            drawnow;  

                            if app.manageStopFlag(f1, FileName, IDmeas, VGSmeas, VDSmeas)
                                return;
                            end


                        end
                        % TDS544A_Y_OffsetSingolaConnessione(app.osc_obj, app.SourceDropDown_VGS.Value, 0.4, 2, VGS(i_vgs), 1);                        
                        VDSdes = VDSdes_plot(i_vds) + calib_Vds; % Desired Drain Voltage
                        
                        % Compensation Algorithm                      
                        if app.VCompButton.Value == 1
                            deltaVg = 0;
                            int_comp_count = 1; % Iterations for multiple checks

                            for int_comp_count = 1:int_comp_iter_limit
                                 TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VGS.Value, max(VGS)/4, -3);
                                 current_yscale = 2.5;
                                 TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_VDS.Value, current_yscale, -3.6); 
                                 current_yscale = current_yscale_init;
                                 TDS544A_YSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value, current_yscale, -3.8); 
                             
                                 NewMessage = 'CompensadeltaVting Gate-Source Voltage';
                                 app.TextEditing(NewMessage, app.logFileName);
                                 [VGS(i_vgs), VGSoffset] = TDS544ACompVoltage_HP_Gate(supply, VGS(i_vgs), VGSdes + deltaVg, VDSdes, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, app.TolVEditField.Value, app.KpEditField.Value, app.KiEditField.Value, app.osc_obj, app.t, app.SourceDropDown_VGS.Value, time(1), time(2), ...
                                     SerialPortParam,  app.Mode_selected, app.PulseWidth_IVF_EditField.Value, app.XoffEditField.Value, app.AttenuazioneDropDown_CH1.Value, app.AttenuazioneDropDown_CH2.Value, app.AttenuazioneDropDown_CH3.Value, app.AttenuazioneDropDown_CH4.Value, app.OffsetCanali);
                                 
                                 if app.manageStopFlag(f1, FileName, IDmeas, VGSmeas, VDSmeas)
                                    return; % Termina completamente il RUN
                                 end
                                 
                                 NewMessage = ['VGS Compensation Factor: ', num2str(VGS(i_vgs) - VGSdes), ' V'];
                                 app.TextEditing(NewMessage, app.logFileName);  
                                
                                 NewMessage = 'Compensating Drain-Source Voltage';
                                 app.TextEditing(NewMessage, app.logFileName);
                                 [VDS(i_vds),current_yoffset, VDSoffset] =  TDS544ACompVoltage_HP(supply, VGS(i_vgs), VDSdes, VDSdes+deltaVd, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, app.TolVEditField.Value, app.KpEditField.Value, app.KiEditField.Value, app.osc_obj, app.t, app.SourceDropDown_VDS.Value, app.SourceDropDown_Current.Value, time(1), time(2), ...
                                     SerialPortParam,  app.Mode_selected, app.PulseWidth_IVF_EditField.Value, app.XoffEditField.Value, app.AttenuazioneDropDown_CH1.Value, app.AttenuazioneDropDown_CH2.Value, app.AttenuazioneDropDown_CH3.Value, app.AttenuazioneDropDown_CH4.Value, app.OffsetCanali);
                                 
                                 if app.manageStopFlag(f1, FileName, IDmeas, VGSmeas, VDSmeas)
                                    return; % Termina completamente il RUN
                                 end
                                 
                                 NewMessage = ['VDS Compensation Factor: ', num2str(VDS(i_vds) - VDSdes), ' V'];
                                 app.TextEditing(NewMessage, app.logFileName);
                                 deltaVd = VDS(i_vds) - VDSdes;

                                 % Vgs check
                                 current_sel = extractAfter(app.SourceDropDown_VGS.Value, 'CH');
                                 % canale = str2double(current_sel);
                                 Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                                 y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                                 deltaVg = VGSdes - mean(y((time(1)<app.t)&(app.t<time(2))));
                                 if (abs(deltaVg) < app.TolVEditField.Value)||((VGS(i_vgs) + deltaVg > app.VgsmaxEditField.Value) &&  int_comp_count > 2)
                                    break;
                                 end
                                
                            end

                        else
                             Vtol = 50;
                             [VDS(i_vds),current_yoffset, VDSoffset] = TDS544ACompVoltage_HP(supply, VGS(i_vgs), VDSdes, VDSdes, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, Vtol, app.KpEditField.Value, app.KiEditField.Value, app.osc_obj, app.t, app.SourceDropDown_VDS.Value, app.SourceDropDown_Current.Value, time(1), time(2), ...
                                 SerialPortParam,  app.Mode_selected, app.PulseWidth_IVF_EditField.Value, app.XoffEditField.Value, app.AttenuazioneDropDown_CH1.Value, app.AttenuazioneDropDown_CH2.Value, app.AttenuazioneDropDown_CH3.Value, app.AttenuazioneDropDown_CH4.Value, app.OffsetCanali);
                            
                             if app.manageStopFlag(f1, FileName, IDmeas, VGSmeas, VDSmeas)
                                    return; % Termina completamente il RUN
                             end
                        
                        
                        end
                                
                        if app.VdsmaxEditField.Value >= VDS(i_vds) &&  (app.VgsmaxEditField.Value + 0.1 >= VGS(i_vgs)) % additional offset is added to increase number of points
                            % Oscilloscope Current Scale Configuration
                            current_sel = extractAfter(app.SourceDropDown_Current.Value, 'CH');
                                 % canale = str2double(current_sel);
                            Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                            y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                            y = y((time(1)<app.t)&(app.t<time(2)));
                            Izoom =[mean(y) , max(y), min(y)];
                            TDS544A_Y_OffsetSingolaConnessione(app.osc_obj, app.SourceDropDown_Current.Value,  abs((Izoom(2) - Izoom(3))/(str2double(app.(Attenuazione).Value)))/2, 0, Izoom(1), app.(Attenuazione).Value, 0);
                            % Zoom(current_yoffset, app.osc_obj, app.SourceDropDown_Current.Value, -3);
                            % Zoom(VGSoffset, app.osc_obj, app.SourceDropDown_VGS.Value, 0);
                            % Zoom(VDSoffset, app.osc_obj, app.SourceDropDown_VDS.Value, 0);
                            for i = 1:app.Iteration.Value
    
                                SupplySetting_HP(supply, VGS(i_vgs), VDS(i_vds), app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 1);
                                pause(0.1);
                                sendDataSTM32(SerialPortParam, app.Mode_selected, 10, app.PulseWidth_IVF_EditField.Value, 100, 120, VDSdes);
                                %SupplySetting_HP(supply, VGS(i_vgs), VDS(i_vds), app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 0);
    
                                %% Gate Voltage
                                current_sel = extractAfter(app.SourceDropDown_VGS.Value, 'CH');
                                % canale = str2double(current_sel);
                                Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                                y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                                VGSmeas(i_vds,i_vgs) = mean(y((time(1)<app.t)&(app.t<time(2)))) + VGSmeas(i_vds,i_vgs);
                                app.plottingData2(app.ch1,app.t,y,tmeas,y((time(1)<app.t)&(app.t<time(2))), 'V');
                        
                                %% Drain Current
                                current_sel = extractAfter(app.SourceDropDown_Current.Value, 'CH');
                                % canale = str2double(current_sel);
                                Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                                y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));
                                IDmeas(i_vds,i_vgs) = mean(y((time(1)<app.t)&(app.t<time(2)))) + IDmeas(i_vds,i_vgs);
                                app.plottingData2(app.ch3,app.t,y,tmeas,y((time(1)<app.t)&(app.t<time(2))), 'A');               
		                                                   
                                %% Drain Voltage
                                current_sel = extractAfter(app.SourceDropDown_VDS.Value, 'CH');
                                % canale = str2double(current_sel);
                                Attenuazione = ['AttenuazioneDropDown_CH', current_sel];
                                y = TDS544A_GetDataSingolaConnessione(app.osc_obj,length(app.t), current_sel, app.XoffEditField.Value, app.(Attenuazione).Value, app.OffsetCanali(str2double(current_sel)));                            
                                VDSmeas(i_vds,i_vgs) = mean(y((time(1)<app.t)&(app.t<time(2)))) + VDSmeas(i_vds,i_vgs);
                                app.plottingData2(app.ch2,app.t,y,tmeas,y((time(1)<app.t)&(app.t<time(2))), 'V');
        
                            end
                        
                            VGSmeas(i_vds,i_vgs) = VGSmeas(i_vds,i_vgs)/app.Iteration.Value;
                            IDmeas(i_vds,i_vgs) = (IDmeas(i_vds,i_vgs)/app.Iteration.Value);
                            VDSmeas(i_vds,i_vgs) = VDSmeas(i_vds,i_vgs)/app.Iteration.Value - calib_Vds;
    
                            NewMessage = ['Measurement Point: VDS ',num2str(VDS(i_vds)), ' V and VGS', num2str(VGS(i_vgs)), ' V'];
                            app.TextEditing(NewMessage, app.logFileName);
                                        
                            x = VDSmeas(:,i_vgs);
                            y1 = IDmeas(:,i_vgs);
                            y2 = VGSmeas(:,i_vgs);
                            subplot(2,1,1);
                            plot(x(x ~= 0),y1(y1 ~= 0),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('Drain Current (A)','FontSize',20);xlabel('Drain Voltage (V)','FontSize',20);grid on;lgd=legend(cellstr(num2str(VGSdes_plot(i_vgs)')),'FontSize',10,'Location','best','LineWidth',1);title(lgd,'Gate Voltage(V)');
                            subplot(2,1,2);
                            plot(x(x ~= 0),(y2(y2 ~= 0)),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('Gate Voltage (V)','FontSize',20);xlabel('Drain Voltage (V)','FontSize',20);grid on;
                        else
                            break;
                        end
                    end
                    close(figure(1));
                    if i_plot > 0
                        close(figure(3));
                    end
                    % f3 = figure(3)
                    % for i_plot=1:i_vgs
                    %     subplot(2,1,1);
                    %     title(strcat('OUTPUT CURVE - DEV: ', app.DeviceEditField.Value),'FontSize',30);
                    %     index_lim = sum(VGSmeas(:, i_plot) ~= 0);
                    %     plot(VDSmeas(1:index_lim,i_plot),IDmeas(1:index_lim,i_plot),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('Drain Current (A)','FontSize',20);xlabel('Drain Voltage (V)','FontSize',20);grid on;
                    %     hold on;
                    %     subplot(2,1,2);
                    %     plot(VDSmeas(1:index_lim,i_plot),VGSmeas(1:index_lim,i_plot)-VGSdes_plot(i_plot),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('V_{GS-meas} - V_{GS-des} (V)','FontSize',20);xlabel('Drain Voltage (V)','FontSize',20);grid on;lgd=legend(cellstr(num2str(VGSdes_plot')),'FontSize',16,'Location','eastoutside','LineWidth',2);title(lgd,'Gate Voltage(V)');
                    %     hold on;
                    % end
                    
                    f3 = figure(3)
                    for i_plot=1:i_vgs
                        subplot(2,1,1);
                        title(strcat('OUTPUT CURVE - DEV: ', app.DeviceEditField.Value),'FontSize',30);
                        index_lim = sum(VGSmeas(:, i_plot) ~= 0);
                        plot(VDSmeas(1:index_lim,i_plot),IDmeas(1:index_lim,i_plot),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('Drain Current (A)','FontSize',20);xlabel('Drain Voltage (V)','FontSize',20);grid on;
                        hold on;
                        
                        subplot(2,1,2);
                        plot(VDSmeas(1:index_lim,i_plot),VGSmeas(1:index_lim,i_plot)-VGSdes_plot(i_plot),'-o','LineWidth',2); set(gca, 'FontSize', 18); ylabel('V_{GS-meas} - V_{GS-des} (V)','FontSize',20);xlabel('Drain Voltage (V)','FontSize',20);grid on;
                        hold on;
                    end
                    subplot(2,1,2);
                    lgd=legend(cellstr(num2str(VGSdes_plot')),'FontSize',16,'Location','eastoutside','LineWidth',2);
                    title(lgd,'Gate Voltage(V)');

                    yval = 10*app.TolVEditField.Value;
                    ylim([-yval yval]);
                    x_fill = [min(VDSmeas(:)) max(VDSmeas(:)) max(VDSmeas(:)) min(VDSmeas(:))];
                    y_fill = [-(app.TolVEditField.Value) -(app.TolVEditField.Value) app.TolVEditField.Value app.TolVEditField.Value];
                    fill(x_fill, y_fill, 'r', 'FaceAlpha', 0.3, 'EdgeColor', 'none','HandleVisibility', 'off');  % fascia rossa semitrasparente
                    text(0.5, 0.1, ['Tolerance = ', num2str(app.TolVEditField.Value), ' V'], 'Units', 'normalized', 'HorizontalAlignment', 'center', 'FontSize', 18, 'Color', 'r')
                    text(0.98, 0.05, [num2str(app.PulseWidth_IVF_EditField.Value), 'us'], 'Units', 'normalized', 'HorizontalAlignment', 'right', 'VerticalAlignment','bottom', 'FontSize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'white', 'EdgeColor', 'black');

                    %% save file   
                    save(strcat(FileName,'.mat'), 'IDmeas', 'VGSmeas', 'VDSmeas');
                    set(f3, 'Units', 'normalized', 'OuterPosition', [0 0 1 1]);
                    saveas(f3, strcat(FileName,'.png'));
                end
                
                NewMessage = 'Output IV Completed';
                app.TextEditing(NewMessage, app.logFileName);
                app.Lamp.Color = [0.25 0.940 0.1250]; % Green
                                                   
            end
            %% Discharge capacitors
            SupplySetting_HP(supply, 0, 0, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 1);
            pause(0.1)
            SupplyCloseCom_HP(supply);
            app.cleanupOscilloscopeDisconnection();
         catch ME
           try
            %Gestione errori riguardo il RUN
            if exist('supply', 'var') && ~isempty(supply)
               SupplySetting_HP(supply, 0, 0, app.osc_obj, app.VgsminEditField.Value, app.VgsmaxEditField.Value, app.VdsminEditField.Value, app.VdsmaxEditField.Value, 1);
               pause(0.1)
               SupplyCloseCom_HP(supply);
            end
           catch
           end
            app.cleanupOscilloscopeDisconnection();
           if app.Stopped == 1 
               messaggioErrore = sprintf('CARATTERIZZAZIONE INTERROTTA DA UTENTE');
               app.TextEditing(messaggioErrore, app.logFileName);
           else    
                messaggioErrore = sprintf('CARATTERIZZAZIONE INTERROTTA PER ERRORE: %s', ME.message);
                app.TextEditing(messaggioErrore, app.logFileName);
                uialert(app.UIFigure, messaggioErrore, 'Errore in RUN');
           end      
         end
        end

        % Value changed function: PulseWidth_IVF_EditField
        function PulseWidth_IVF_EditFieldValueChanged(app, event)
            NewMessage = 'Pulse Width Changed';
            app.TextEditing(NewMessage, app.logFileName);
        end

        % Value changed function: SetdTemp_EditField
        function SetdTemp_EditFieldValueChanged(app, event)
            if app.SetdTemp_EditField.Value == 0
                app.SetTemp2_EditField.Enable = "off";
                NewMessage = ['Single Temperature Point: ',num2str(app.SetTemp1.Value), ' °C'];
                app.TextEditing(NewMessage, app.logFileName);
            else
                app.SetTemp2_EditField.Enable = "on";
            end
            
        end

        % Value changed function: VDSStepEditField
        function VDSStepEditFieldValueChanged(app, event)
            if app.VDSStepEditField.Value == 0
                app.VDS2VEditField.Enable = "off";
                NewMessage = ['Single Drain-Source Voltage Point: ',num2str(app.VDS1VEditField.Value), ' V'];
                app.TextEditing(NewMessage, app.logFileName);
            else
                app.VDS2VEditField.Enable = "on";
            end 
        end

        % Value changed function: VGSStepEditField
        function VGSStepEditFieldValueChanged(app, event)
            if app.VGSStepEditField.Value == 0
                app.VGS2VEditField.Enable = "off";
                NewMessage = ['Single Gate-Source Voltage Point: ',num2str(app.VGS1VEditField.Value), ' V'];
                app.TextEditing(NewMessage, app.logFileName);
            else
                app.VGS2VEditField.Enable = "on";
            end    
        end

        % Value changed function: VCompButton
        function VCompButtonValueChanged(app, event)
            if  app.VCompButton.Value == 1
                NewMessage = 'PI Compensation Algorithm Activated : the voltage drop due to the setup circuit is compensated'; 
                app.TextEditing(NewMessage, app.logFileName);
            else
                NewMessage = 'Compensation Algorithm Disactivated: the voltage drop due to the setup circuit is not compensated'; 
                app.TextEditing(NewMessage, app.logFileName);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Parameters Automatic Saving 
            % SaveAppConfig(app);
            if isvalid(app.Timer) && strcmp(app.Timer.Running, 'on')
                stop(app.Timer);
            end
            delete(app.Timer);

            if isvalid(app.Timer2) && strcmp(app.Timer2.Running, 'on')
                stop(app.Timer2);
            end
            delete(app.Timer2);
            
            app.cleanupOscilloscopeDisconnection();
            delete(app);
            
        end

        % Value changed function: FeedbackButton
        function FeedbackButtonValueChanged(app, event)
            if app.FeedbackButton.Value == 0
                app.VoffVEditField.Enable = "off";
                NewMessage = ['Bang Band Algorithm Disactivated'];
                app.TextEditing(NewMessage, app.logFileName);
            else
                app.VoffVEditField.Enable = "on";
            end  
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            Tdes = app.SetTemp_EditField.Value;
            Temp_th = 30; % Temperature Threshold

            %% Manual Setting Von and Voff for the temperature range
            if (Tdes <= Temp_th)
                    SupplySetting_HP_Temp(0, 0, app.LimitVEditField.Value, 1);
                    NewMessage = ['WARNING: Temperature Threshold Point: ',num2str(Temp_th), ' °C'];
                    app.TextEditing(NewMessage, app.logFileName);
                    app.Lamp.Color = [0.9290 0.6940 0.1250]; % Orange
                    app.temp_set = 0;
            else

                % Temperature Figure
                app.ftemp = figure('Name','Heater Control','NumberTitle','off', ...
                    'UserData',app);

                % Subplot 1: Temperatura
                subplot(2,1,1);
                app.plotHandleTemp = plot(nan, nan, 'r', 'LineWidth', 2); grid on; xlabel('Time (s)'); ylabel('Temperature [°C]'); 
                title('Temperature Monitoring: Setting Voltages');
    
                % Subplot 2: Tensione applicata
                subplot(2,1,2);
                app.plotHandleVolt = plot(nan, nan, 'b', 'LineWidth', 2); grid on; xlabel('Time (s)'); ylabel('Applied Voltage [V]');

                % STOP Button
                uicontrol('Style', 'pushbutton', 'String', 'STOP','Position', [20 20 60 30], ...
                    'FontSize',12,'FontWeight','bold','Callback',@(src,evt) setStopFlag(src));
                

                % Save Figure Structure
                set(app.ftemp,'UserData',app);

                % Start timer for voltage tuning
                start(app.timer);

            end


            if app.Button.Value == 1 && Tdes > Temp_th && app.FeedbackButton.Value == 1

                app.ftemp = figure('Name', 'Temperature and Voltage Monitoring');
                %app.ftemp.CloseRequestFcn = @(src, event) disp('Figura non può essere chiusa');
                % Subplot 1: Temperature
                subplot(2,1,1);
                app.plotHandleTemp = plot(nan, nan, 'r', 'LineWidth', 2); grid on; xlabel('Time (s)'); ylabel('Temperature [°C]'); 
                % Subplot 2: Applied Voltage
                subplot(2,1,2);
                app.plotHandleVolt = plot(nan, nan, 'b', 'LineWidth', 2); grid on; xlabel('Time (s)'); ylabel('Applied Voltage [V]');

                start(app.Timer2); % Start Timer 2

            else
                stop(app.Timer2); % Stop Timer 2
                app.temp_meas = [];
                app.temp_volt = [];
                app.temp_time = [];
                app.temp_index = -1; % Reset indice
                close(app.ftemp);

            end
        end

        % Button pushed function: InitSupplyButton
        function InitSupplyButtonPushed(app, event)
            init_DSC(app.InputVEditField.Value, app.MaxIVEditField.Value); % for DSC DC Supply
        end

        % Button pushed function: SingleSeq
        function SingleSeqButtonPushed(app, event)
            try
                TDS544A_SingleSeqSingolaConnessione(app.osc_obj);  % ← SENZA app.t =
                app.TextEditing('✓ OSC single seq OK', app.logFileName);
            catch ME
                app.cleanupOscilloscopeDisconnection();
                messaggioErroreSQ = sprintf('✗ OSC Errore SingleSeq: %s', ME.message);
                app.TextEditing(messaggioErroreSQ, app.logFileName);
                uialert(app.UIFigure, messaggioErroreSQ, 'Errore in SingleSeq');
            end
        end

        % Button pushed function: ConnectTDS544AButton
        function ConnectTDS544AButtonPushed(app, event)
            app.cleanupOscilloscopeDisconnection();
            instrreset;
            try
                % Crea la stringa e l'oggetto
                str_ip = str2double(app.IPosc_EditField.Value);
                app.osc_obj = gpib('NI', 0, str_ip);
                
                % IMPOSTA UN BUFFER GRANDE UNA VOLTA SOLA
                app.osc_obj.InputBufferSize = 100000; % 100 KB
                
                % Apri la comunicazione
                fopen(app.osc_obj);
                
                % Inizializzazione di base
                clrdevice(app.osc_obj);
                fprintf(app.osc_obj, '*CLS'); 
                fprintf(app.osc_obj, 'HEADer OFF');
                
                % Cambia colore alla lampada per confermare
                app.TDS544ALamp.Color = [0 1 0]; % Verde
                app.TextEditing('Oscilloscope Connected!', app.logFileName);
            catch ME
                app.TDS544ALamp.Color = [1 0 0]; % Rosso
                app.TextEditing(['Connection Error: ', ME.message], app.logFileName);
            end
        end

        % Button pushed function: Auto_ZeroCH1Button
        function Auto_ZeroCH1ButtonPushed(app, event)
         try
          if isempty(app.t)
            app.flag = 0;
          end
          if app.flag == 0
            app.t = TDS544A_GetXSingolaConnessione(app.osc_obj);
            app.flag = 1;
          end
            Azero = TDS544A_GetDataSingolaConnessione(app.osc_obj, length(app.t), '1', app.XoffEditField.Value, app.AttenuazioneDropDown_CH1.Value, 0);
            Zero1 = mean(Azero);
            app.OffsetCanali(1) = Zero1;

            %             %Debug
            % app.OffsetCanali(1) = 0;
            % app.ZeroCH1.Value = 0;


            app.StatoAutozero(1) = true;
            app.ZeroCH1.Value = Zero1;
          if all(app.StatoAutozero)
            app.IVFRUNButton.Enable = 'on';
            app.IVFRUNButton.BackgroundColor = [0 1 0];
          end
         catch ME
            uialert(app.UIFigure, ['Errore AutoZero CH1 :' ME.message], 'Errore', 'Icon','error');
         end
        end

        % Button pushed function: Auto_ZeroCH2Button
        function Auto_ZeroCH2ButtonPushed(app, event)
         try
          if isempty(app.t)
            app.flag = 0;
          end
          if app.flag == 0
            app.t = TDS544A_GetXSingolaConnessione(app.osc_obj);
            app.flag = 1;
          end
            Azero = TDS544A_GetDataSingolaConnessione(app.osc_obj, length(app.t), '2', app.XoffEditField.Value, app.AttenuazioneDropDown_CH2.Value, 0);
            Zero2 = mean(Azero);
            app.OffsetCanali(2) = Zero2;

            %             %Debug
            % app.OffsetCanali(2) = 0;
            % app.ZeroCH2.Value = 0;


            app.StatoAutozero(2) = true;
            app.ZeroCH2.Value = Zero2;
          if all(app.StatoAutozero)
            app.IVFRUNButton.Enable = 'on';
            app.IVFRUNButton.BackgroundColor = [0 1 0];
          end
         catch ME
            uialert(app.UIFigure, ['Errore AutoZero CH2 :' ME.message], 'Errore', 'Icon','error');
         end
        end

        % Button pushed function: Auto_ZeroCH3Button
        function Auto_ZeroCH3ButtonPushed(app, event)
         try
          if isempty(app.t)
            app.flag = 0;
          end
          if app.flag == 0
            app.t = TDS544A_GetXSingolaConnessione(app.osc_obj);
            app.flag = 1;
          end
            Azero = TDS544A_GetDataSingolaConnessione(app.osc_obj, length(app.t), '3', app.XoffEditField.Value, app.AttenuazioneDropDown_CH3.Value, 0);
            Zero3 = mean(Azero);
            app.OffsetCanali(3) = Zero3;
            % %Debug
            % app.OffsetCanali(3) = 0;
            % app.ZeroCH3.Value = 0;
            
            app.StatoAutozero(3) = true;
            app.ZeroCH3.Value = Zero3;
          if all(app.StatoAutozero)
            app.IVFRUNButton.Enable = 'on';
            app.IVFRUNButton.BackgroundColor = [0 1 0];
          end
         catch ME
            uialert(app.UIFigure, ['Errore AutoZero CH3 :' ME.message], 'Errore', 'Icon','error');
         end            
        end

        % Button pushed function: Auto_ZeroCH4Button
        function Auto_ZeroCH4ButtonPushed(app, event)
         try
          if isempty(app.t)
            app.flag = 0;
          end
          if app.flag == 0
            app.t = TDS544A_GetXSingolaConnessione(app.osc_obj);
            app.flag = 1;
          end
            Azero = TDS544A_GetDataSingolaConnessione(app.osc_obj, length(app.t), '4', app.XoffEditField.Value, app.AttenuazioneDropDown_CH4.Value, 0);
            Zero4 = mean(Azero);
            app.OffsetCanali(4) = Zero4;
            
            % %Debug
            % app.OffsetCanali(4) = 0;
            % app.ZeroCH4.Value = 0;
            
            app.StatoAutozero(4) = true;
            app.ZeroCH4.Value = Zero4;
          if all(app.StatoAutozero)
            app.IVFRUNButton.Enable = 'on';
            app.IVFRUNButton.BackgroundColor = [0 1 0];
          end
         catch ME
            uialert(app.UIFigure, ['Errore AutoZero CH4 :' ME.message], 'Errore', 'Icon','error');
         end
        end

        % Value changed function: VCompButton_2
        function VCompButton_2ValueChanged(app, event)
            value = app.VCompButton_2.Value;
            if value == 1
                NewMessage = 'VDS Calibration enabled - first VDS is 0';
                app.TextEditing(NewMessage, app.logFileName);
            end
        end

        % Value changed function: VGSStepEditField_out
        function VGSStepEditField_outValueChanged(app, event)
            if app.VGSStepEditField_out.Value == 0
                app.VGS2VEditField_out.Enable = "off";
                NewMessage = ['Single Gate-Source Voltage Point: ',num2str(app.VGS1VEditField.Value), ' V'];
                app.TextEditing(NewMessage, app.logFileName);
            else
                app.VGS2VEditField_out.Enable = "on";
            end   
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {730, 730};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {425, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IVFORWARDTRANSFERTab = uitab(app.TabGroup);
            app.IVFORWARDTRANSFERTab.Title = 'IV FORWARD TRANSFER';
            app.VGS1VEditFieldLabel = uilabel(app.IVFORWARDTRANSFERTab);
            app.VGS1VEditFieldLabel.Text = 'VGS1 (V)';
            app.VGS1VEditField = uieditfield(app.IVFORWARDTRANSFERTab, 'numeric');
            app.GateSourceVoltageLabel_3 = uilabel(app.IVFORWARDTRANSFERTab);
            app.GateSourceVoltageLabel_3.Text = 'Gate-Source Voltage ';
            app.DrainSourceVoltageLabel = uilabel(app.IVFORWARDTRANSFERTab);
            app.DrainSourceVoltageLabel.Text = 'Drain-Source Voltage ';
            app.VGS2VEditFieldLabel = uilabel(app.IVFORWARDTRANSFERTab);
            app.VGS2VEditFieldLabel.Text = 'VGS2 (V)';
            app.VGS2VEditField = uieditfield(app.IVFORWARDTRANSFERTab, 'numeric');
            app.VGS2VEditField.Limits = [0 100];
            app.VGS2VEditField.Value = 20;
            app.StepVEditFieldLabel = uilabel(app.IVFORWARDTRANSFERTab);
            app.StepVEditFieldLabel.Text = 'Step (V)';
            app.VGSStepEditField = uieditfield(app.IVFORWARDTRANSFERTab, 'numeric');
            app.VGSStepEditField.Limits = [0 100];
            app.VGSStepEditField.ValueChangedFcn = createCallbackFcn(app, @VGSStepEditFieldValueChanged, true);
            app.VGSStepEditField.Value = 1;
            app.PulseWidthusEditFieldLabel_2 = uilabel(app.IVFORWARDTRANSFERTab);
            app.PulseWidthusEditFieldLabel_2.Text = 'Pulse Width (us)';
            app.PulseWidth_IVF_EditField = uieditfield(app.IVFORWARDTRANSFERTab, 'numeric');
            app.PulseWidth_IVF_EditField.Limits = [0 500];
            app.PulseWidth_IVF_EditField.ValueChangedFcn = createCallbackFcn(app, @PulseWidth_IVF_EditFieldValueChanged, true);
            app.PulseWidth_IVF_EditField.Value = 60;
            app.SourceDropDown_2Label = uilabel(app.IVFORWARDTRANSFERTab);
            app.SourceDropDown_2Label.Text = 'Source';
            app.SourceDropDown_VGS = uidropdown(app.IVFORWARDTRANSFERTab);
            app.SourceDropDown_VGS.Items = {'CH1', 'CH2', 'CH3', 'CH4', 'CH5', 'CH6'};
            app.SourceDropDown_VGS.Value = 'CH1';
            app.SourceDropDown_Vds = uilabel(app.IVFORWARDTRANSFERTab);
            app.SourceDropDown_Vds.Text = 'Source';
            app.SourceDropDown_VDS = uidropdown(app.IVFORWARDTRANSFERTab);
            app.SourceDropDown_VDS.Items = {'CH1', 'CH2', 'CH3', 'CH4', 'CH5', 'CH6'};
            app.SourceDropDown_VDS.Value = 'CH2';
            app.VDS1VEditFieldLabel = uilabel(app.IVFORWARDTRANSFERTab);
            app.VDS1VEditFieldLabel.Text = 'VDS1 (V)';
            app.VDS1VEditField = uieditfield(app.IVFORWARDTRANSFERTab, 'numeric');
            app.VDS1VEditField.Limits = [0 Inf];
            app.VDS1VEditField.Value = 5;
            app.VDS2VEditFieldLabel = uilabel(app.IVFORWARDTRANSFERTab);
            app.VDS2VEditFieldLabel.Text = 'VDS2 (V)';
            app.VDS2VEditField = uieditfield(app.IVFORWARDTRANSFERTab, 'numeric');
            app.VDS2VEditField.Limits = [0 100];
            app.VDS2VEditField.Enable = 'off';
            app.VDS2VEditField.Value = 15;
            app.StepVEditField_2Label = uilabel(app.IVFORWARDTRANSFERTab);
            app.StepVEditField_2Label.Text = 'Step (V)';
            app.VDSStepEditField = uieditfield(app.IVFORWARDTRANSFERTab, 'numeric');
            app.VDSStepEditField.Limits = [0 100];
            app.VDSStepEditField.ValueChangedFcn = createCallbackFcn(app, @VDSStepEditFieldValueChanged, true);
            app.SourceDropDown_Curr = uilabel(app.IVFORWARDTRANSFERTab);
            app.SourceDropDown_Curr.Text = 'Current Source';
            app.SourceDropDown_Current = uidropdown(app.IVFORWARDTRANSFERTab);
            app.SourceDropDown_Current.Items = {'CH1', 'CH2', 'CH3', 'CH4', 'CH5', 'CH6'};
            app.SourceDropDown_Current.Value = 'CH3';
            app.IterationNumberLabel = uilabel(app.IVFORWARDTRANSFERTab);
            app.IterationNumberLabel.Text = 'Iteration';
            app.Iteration = uieditfield(app.IVFORWARDTRANSFERTab, 'numeric');
            app.Iteration.Limits = [0 500];
            app.Iteration.Value = 1;
            app.IVFORWARDOUTPUTTab = uitab(app.TabGroup);
            app.IVFORWARDOUTPUTTab.Title = 'IV FORWARD OUTPUT';
            app.GateSourceVoltageLabel_4 = uilabel(app.IVFORWARDOUTPUTTab);
            app.GateSourceVoltageLabel_4.Text = 'Gate-Source Voltage  ';
            app.DrainSourceVoltageLabel_3 = uilabel(app.IVFORWARDOUTPUTTab);
            app.DrainSourceVoltageLabel_3.Text = 'Drain-Source Voltage  ';
            app.VDSStepEditField_out = uieditfield(app.IVFORWARDOUTPUTTab, 'numeric');
            app.VDSStepEditField_out.Limits = [0 100];
            app.VDSStepEditField_out.Value = 1;
            app.DropDown = uidropdown(app.IVFORWARDOUTPUTTab);
            app.DropDown.Items = {'Lin', 'Log'};
            app.DropDown.ItemsData = [1 2];
            app.DropDown.Value = 1;
            app.IncrementEditField = uilabel(app.IVFORWARDOUTPUTTab);
            app.IncrementEditField.Text = 'Step (V)';
            app.VGS2VEditField_2Label = uilabel(app.IVFORWARDOUTPUTTab);
            app.VGS2VEditField_2Label.Text = 'VGS2 (V)';
            app.VGS2VEditField_out = uieditfield(app.IVFORWARDOUTPUTTab, 'numeric');
            app.VGS2VEditField_out.Limits = [0 100];
            app.VGS2VEditField_out.Enable = 'off';
            app.VGS2VEditField_out.Value = 20;
            app.StepVEditFieldLabel_2 = uilabel(app.IVFORWARDOUTPUTTab);
            app.StepVEditFieldLabel_2.Text = 'Step (V)';
            app.VGSStepEditField_out = uieditfield(app.IVFORWARDOUTPUTTab, 'numeric');
            app.VGSStepEditField_out.Limits = [0 100];
            app.VGSStepEditField_out.ValueChangedFcn = createCallbackFcn(app, @VGSStepEditField_outValueChanged, true);
            app.VDS1VEditField_2Label = uilabel(app.IVFORWARDOUTPUTTab);
            app.VDS1VEditField_2Label.Text = 'VDS1 (V)';
            app.VDS1VEditField_out = uieditfield(app.IVFORWARDOUTPUTTab, 'numeric');
            app.VDS1VEditField_out.Limits = [0 Inf];
            app.VDS2VEditField_2Label = uilabel(app.IVFORWARDOUTPUTTab);
            app.VDS2VEditField_2Label.Text = 'VDS2 (V)';
            app.VDS2VEditField_out = uieditfield(app.IVFORWARDOUTPUTTab, 'numeric');
            app.VDS2VEditField_out.Limits = [0 100];
            app.VDS2VEditField_out.Value = 20;
            app.VGS1VEditField_2Label = uilabel(app.IVFORWARDOUTPUTTab);
            app.VGS1VEditField_2Label.Text = 'VGS1 (V)';
            app.VGS1VEditField_out = uieditfield(app.IVFORWARDOUTPUTTab, 'numeric');
            app.VGS1VEditField_out.Value = 8;
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.NucleoSTM32Tab = uitab(app.TabGroup2);
            app.NucleoSTM32Tab.Title = 'Nucleo STM32';
            app.Panel = uipanel(app.NucleoSTM32Tab);
            app.PortEditFieldLabel = uilabel(app.Panel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.Panel, 'text');
            app.PortEditField.Value = 'COM8';
            app.StopBitDropDownLabel = uilabel(app.Panel);
            app.StopBitDropDownLabel.Text = 'StopBit';
            app.StopBitDropDown = uidropdown(app.Panel);
            app.StopBitDropDown.Items = {'1', '2'};
            app.StopBitDropDown.Value = '1';
            app.BaudRateEditFieldLabel = uilabel(app.Panel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.Panel, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.ParityDropDownLabel = uilabel(app.Panel);
            app.ParityDropDownLabel.Text = 'Parity';
            app.ParityDropDown = uidropdown(app.Panel);
            app.ParityDropDown.Items = {'None', 'Even', 'Odd'};
            app.ParityDropDown.Value = 'None';
            app.WordLengthDropDownLabel = uilabel(app.Panel);
            app.WordLengthDropDownLabel.Text = 'Word Length';
            app.WordLengthDropDown = uidropdown(app.Panel);
            app.WordLengthDropDown.Items = {'8', '9'};
            app.WordLengthDropDown.Value = '8';
            app.TerminatorEditFieldLabel = uilabel(app.Panel);
            app.TerminatorEditFieldLabel.Text = 'Terminator';
            app.TerminatorEditField = uieditfield(app.Panel, 'text');
            app.TerminatorEditField.Value = 'LF';
            app.STM32ConfigButton = uibutton(app.Panel, 'push');
            app.STM32ConfigButton.ButtonPushedFcn = createCallbackFcn(app, @STM32ConfigButtonPushed, true);
            app.STM32ConfigButton.Text = 'STM32 CONFIG';
            app.SettingsLabel = uilabel(app.NucleoSTM32Tab);
            app.SettingsLabel.Text = 'Settings';
            app.OscilloscopeXTab = uitab(app.TabGroup2);
            app.OscilloscopeXTab.Title = 'Oscilloscope X';
            app.TRIGGERLabel = uilabel(app.OscilloscopeXTab);
            app.TRIGGERLabel.Text = ' TRIGGER';
            app.GETDATAButton = uibutton(app.OscilloscopeXTab, 'push');
            app.GETDATAButton.ButtonPushedFcn = createCallbackFcn(app, @GETDATAButtonPushed, true);
            app.GETDATAButton.Text = 'GET DATA';
            app.CONFIGXButton = uibutton(app.OscilloscopeXTab, 'push');
            app.CONFIGXButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIGXButtonPushed, true);
            app.CONFIGXButton.Text = 'CONFIG X';
            app.IPEditFieldLabel = uilabel(app.OscilloscopeXTab);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPosc_EditField = uieditfield(app.OscilloscopeXTab, 'text');
            app.IPosc_EditField.Value = '16';
            app.PulseDelayusEditField_2Label_2 = uilabel(app.OscilloscopeXTab);
            app.PulseDelayusEditField_2Label_2.Text = 'Time  (s/div)';
            app.Time_EditField = uieditfield(app.OscilloscopeXTab, 'numeric');
            app.Time_EditField.Limits = [0 1000];
            app.Time_EditField.ValueDisplayFormat = '%.2e';
            app.Time_EditField.ValueChangedFcn = createCallbackFcn(app, @Time_EditFieldValueChanged, true);
            app.Time_EditField.Value = 1e-06;
            app.ModeDropDownLabel = uilabel(app.OscilloscopeXTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.OscilloscopeXTab);
            app.ModeDropDown.Items = {'NORM', 'AUTO'};
            app.ModeDropDown.Value = 'NORM';
            app.SlopeDropDownLabel = uilabel(app.OscilloscopeXTab);
            app.SlopeDropDownLabel.Text = 'Slope';
            app.SlopeDropDown = uidropdown(app.OscilloscopeXTab);
            app.SlopeDropDown.Items = {'RISE', 'FALL', 'EITHER'};
            app.SlopeDropDown.Value = 'RISE';
            app.SourceDropDownLabel = uilabel(app.OscilloscopeXTab);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown_TRG = uidropdown(app.OscilloscopeXTab);
            app.SourceDropDown_TRG.Items = {'CH1', 'CH2', 'CH3', 'CH4', 'CH5', 'CH6'};
            app.SourceDropDown_TRG.Value = 'CH4';
            app.PulseDelayusEditField_2Label_3 = uilabel(app.OscilloscopeXTab);
            app.PulseDelayusEditField_2Label_3.Text = 'Pos (%)';
            app.TrigPos_EditField = uieditfield(app.OscilloscopeXTab, 'numeric');
            app.TrigPos_EditField.Limits = [0 100];
            app.TrigPos_EditField.Value = 5;
            app.PulseDelayusEditField_2Label_4 = uilabel(app.OscilloscopeXTab);
            app.PulseDelayusEditField_2Label_4.Text = 'Level';
            app.TrigLevel_EditField = uieditfield(app.OscilloscopeXTab, 'numeric');
            app.TrigLevel_EditField.Limits = [-10000 10000];
            app.TrigLevel_EditField.Value = 5;
            app.PulseDelayusEditField_2Label_5 = uilabel(app.OscilloscopeXTab);
            app.PulseDelayusEditField_2Label_5.Text = 'Window (s)';
            app.TimeWind_EditField = uieditfield(app.OscilloscopeXTab, 'numeric');
            app.TimeWind_EditField.Limits = [0 1000];
            app.TimeWind_EditField.ValueDisplayFormat = '%.2e';
            app.TimeWind_EditField.Editable = 'off';
            app.TimeWind_EditField.Value = 1e-05;
            app.SingleSeq = uibutton(app.OscilloscopeXTab, 'push');
            app.SingleSeq.ButtonPushedFcn = createCallbackFcn(app, @SingleSeqButtonPushed, true);
            app.SingleSeq.Text = 'Single/Seq';
            app.RecordLengthDropDownLabel = uilabel(app.OscilloscopeXTab);
            app.RecordLengthDropDownLabel.Text = 'RecordLength';
            app.RecordLengthDropDown = uidropdown(app.OscilloscopeXTab);
            app.RecordLengthDropDown.Items = {'500', '1000', '2500', '5000', '15000'};
            app.RecordLengthDropDown.Value = '500';
            app.XoffEditField = uieditfield(app.OscilloscopeXTab, 'numeric');
            app.XoffEditField.Limits = [0 100];
            app.XoffEditField.Value = 50;
            app.XoffEditFieldLabel = uilabel(app.OscilloscopeXTab);
            app.XoffEditFieldLabel.Text = 'Xoff (%)';
            app.ConnectTDS544AButton = uibutton(app.OscilloscopeXTab, 'push');
            app.ConnectTDS544AButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectTDS544AButtonPushed, true);
            app.ConnectTDS544AButton.Text = 'Connect TDS544A';
            app.TDS544ALampLabel = uilabel(app.OscilloscopeXTab);
            app.TDS544ALampLabel.Text = 'TDS544A';
            app.TDS544ALamp = uilamp(app.OscilloscopeXTab);
            app.OscilloscopeYTab = uitab(app.TabGroup2);
            app.OscilloscopeYTab.Title = 'Oscilloscope Y';
            app.Channel1CheckBoxLabel = uilabel(app.OscilloscopeYTab);
            app.Channel1CheckBoxLabel.Text = 'Channel 1';
            app.Channel1CheckBox = uicheckbox(app.OscilloscopeYTab);
            app.Channel1CheckBox.Text = '';
            app.Channel1CheckBox.Value = true;
            app.Channel2CheckBoxLabel = uilabel(app.OscilloscopeYTab);
            app.Channel2CheckBoxLabel.Text = 'Channel 2';
            app.Channel2CheckBox = uicheckbox(app.OscilloscopeYTab);
            app.Channel2CheckBox.Text = '';
            app.Channel2CheckBox.Value = true;
            app.Channel3CheckBoxLabel = uilabel(app.OscilloscopeYTab);
            app.Channel3CheckBoxLabel.Text = 'Channel 3';
            app.Channel3CheckBox = uicheckbox(app.OscilloscopeYTab);
            app.Channel3CheckBox.Text = '';
            app.Channel3CheckBox.Value = true;
            app.Channel4CheckBoxLabel = uilabel(app.OscilloscopeYTab);
            app.Channel4CheckBoxLabel.Text = 'Channel 4';
            app.Channel4CheckBox = uicheckbox(app.OscilloscopeYTab);
            app.Channel4CheckBox.Text = '';
            app.Channel4CheckBox.Value = true;
            app.VdivLabel = uilabel(app.OscilloscopeYTab);
            app.VdivLabel.Text = 'V/div';
            app.Scale_ch1_EditField = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.Scale_ch1_EditField.Limits = [0 1000];
            app.Scale_ch1_EditField.Value = 0.5;
            app.VdivLabel_2 = uilabel(app.OscilloscopeYTab);
            app.VdivLabel_2.Text = 'V/div';
            app.Scale_ch2_EditField = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.Scale_ch2_EditField.Limits = [0 1000];
            app.Scale_ch2_EditField.Value = 0.5;
            app.VdivLabel_3 = uilabel(app.OscilloscopeYTab);
            app.VdivLabel_3.Text = 'V/div';
            app.Scale_ch3_EditField = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.Scale_ch3_EditField.Limits = [0 1000];
            app.Scale_ch3_EditField.Value = 4;
            app.VdivLabel_4 = uilabel(app.OscilloscopeYTab);
            app.VdivLabel_4.Text = 'V/div';
            app.Scale_ch4_EditField = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.Scale_ch4_EditField.Limits = [0 1000];
            app.Scale_ch4_EditField.Value = 5;
            app.ch1VA = uidropdown(app.OscilloscopeYTab);
            app.ch1VA.Items = {'V', 'A'};
            app.ch1VA.Value = 'V';
            app.ch2VA = uidropdown(app.OscilloscopeYTab);
            app.ch2VA.Items = {'V', 'A'};
            app.ch2VA.Value = 'V';
            app.ch3VA = uidropdown(app.OscilloscopeYTab);
            app.ch3VA.Items = {'V', 'A'};
            app.ch3VA.Value = 'A';
            app.ch4VA = uidropdown(app.OscilloscopeYTab);
            app.ch4VA.Items = {'V', 'A'};
            app.ch4VA.Value = 'V';
            app.VdivLabel_8 = uilabel(app.OscilloscopeYTab);
            app.VdivLabel_8.Text = 'Pos';
            app.ch1DC = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.ch1DC.Limits = [-1000 1000];
            app.ch1DC.Value = -3;
            app.VdivLabel_9 = uilabel(app.OscilloscopeYTab);
            app.VdivLabel_9.Text = 'Pos';
            app.ch2DC = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.ch2DC.Limits = [-1000 1000];
            app.VdivLabel_10 = uilabel(app.OscilloscopeYTab);
            app.VdivLabel_10.Text = 'Pos';
            app.ch3DC = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.ch3DC.Limits = [-1000 1000];
            app.ch3DC.Value = -3;
            app.VdivLabel_11 = uilabel(app.OscilloscopeYTab);
            app.VdivLabel_11.Text = 'Pos';
            app.ch4DC = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.ch4DC.Limits = [-1000 1000];
            app.ch4DC.Value = -2;
            app.CONFIGYButton = uibutton(app.OscilloscopeYTab, 'push');
            app.CONFIGYButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIGYButtonPushed, true);
            app.CONFIGYButton.Text = 'CONFIG Y';
            app.Auto_ZeroCH1Button = uibutton(app.OscilloscopeYTab, 'push');
            app.Auto_ZeroCH1Button.ButtonPushedFcn = createCallbackFcn(app, @Auto_ZeroCH1ButtonPushed, true);
            app.Auto_ZeroCH1Button.Text = 'Auto_Zero CH1';
            app.Auto_ZeroCH2Button = uibutton(app.OscilloscopeYTab, 'push');
            app.Auto_ZeroCH2Button.ButtonPushedFcn = createCallbackFcn(app, @Auto_ZeroCH2ButtonPushed, true);
            app.Auto_ZeroCH2Button.Text = 'Auto_Zero CH2';
            app.Auto_ZeroCH3Button = uibutton(app.OscilloscopeYTab, 'push');
            app.Auto_ZeroCH3Button.ButtonPushedFcn = createCallbackFcn(app, @Auto_ZeroCH3ButtonPushed, true);
            app.Auto_ZeroCH3Button.Text = 'Auto_Zero CH3';
            app.Auto_ZeroCH4Button = uibutton(app.OscilloscopeYTab, 'push');
            app.Auto_ZeroCH4Button.ButtonPushedFcn = createCallbackFcn(app, @Auto_ZeroCH4ButtonPushed, true);
            app.Auto_ZeroCH4Button.Text = 'Auto_Zero CH4';
            app.ZeroCH1 = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.ZeroCH1.Editable = 'off';
            app.ZeroCH2 = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.ZeroCH2.Editable = 'off';
            app.ZeroCH3 = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.ZeroCH3.Editable = 'off';
            app.ZeroCH4 = uieditfield(app.OscilloscopeYTab, 'numeric');
            app.ZeroCH4.Editable = 'off';
            app.AttenuazioneDropDown_2Label = uilabel(app.OscilloscopeYTab);
            app.AttenuazioneDropDown_2Label.Text = 'Attenuazione';
            app.AttenuazioneDropDown_CH1 = uidropdown(app.OscilloscopeYTab);
            app.AttenuazioneDropDown_CH1.Items = {'0.5', '1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000'};
            app.AttenuazioneDropDown_CH1.Value = '1';
            app.AttenuazioneDropDown_CH2 = uidropdown(app.OscilloscopeYTab);
            app.AttenuazioneDropDown_CH2.Items = {'0.5', '1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000'};
            app.AttenuazioneDropDown_CH2.Value = '1';
            app.AttenuazioneDropDown_3Label = uilabel(app.OscilloscopeYTab);
            app.AttenuazioneDropDown_3Label.Text = 'Attenuazione';
            app.AttenuazioneDropDown_CH3 = uidropdown(app.OscilloscopeYTab);
            app.AttenuazioneDropDown_CH3.Items = {'0.5', '1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000'};
            app.AttenuazioneDropDown_CH3.Value = '10';
            app.AttenuazioneDropDown_4Label = uilabel(app.OscilloscopeYTab);
            app.AttenuazioneDropDown_4Label.Text = 'Attenuazione';
            app.AttenuazioneDropDown_CH4 = uidropdown(app.OscilloscopeYTab);
            app.AttenuazioneDropDown_CH4.Items = {'0.5', '1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000'};
            app.AttenuazioneDropDown_CH4.Value = '1';
            app.AttenuazioneLabel = uilabel(app.OscilloscopeYTab);
            app.AttenuazioneLabel.Text = 'Attenuazione';
            app.VoltageSuppliesTab = uitab(app.TabGroup2);
            app.VoltageSuppliesTab.Title = 'Voltage Supplies';
            app.PulseDelayusEditField_2Label_6 = uilabel(app.VoltageSuppliesTab);
            app.PulseDelayusEditField_2Label_6.Text = 'Max Voltage (V)';
            app.VgsmaxEditField = uieditfield(app.VoltageSuppliesTab, 'numeric');
            app.VgsmaxEditField.Limits = [1 100];
            app.VgsmaxEditField.Value = 20;
            app.PulseDelayusEditField_2Label_7 = uilabel(app.VoltageSuppliesTab);
            app.PulseDelayusEditField_2Label_7.Text = 'Max Voltage (V)';
            app.VdsmaxEditField = uieditfield(app.VoltageSuppliesTab, 'numeric');
            app.VdsmaxEditField.Limits = [1 1000];
            app.VdsmaxEditField.Value = 40;
            app.PulseDelayusEditField_2Label_8 = uilabel(app.VoltageSuppliesTab);
            app.PulseDelayusEditField_2Label_8.Text = 'Min Voltage (V)';
            app.VgsminEditField = uieditfield(app.VoltageSuppliesTab, 'numeric');
            app.VgsminEditField.Limits = [0 100];
            app.PulseDelayusEditField_2Label_9 = uilabel(app.VoltageSuppliesTab);
            app.PulseDelayusEditField_2Label_9.Text = 'Min Voltage (V)';
            app.VdsminEditField = uieditfield(app.VoltageSuppliesTab, 'numeric');
            app.VdsminEditField.Limits = [0 1000];
            app.IDLabel_3 = uilabel(app.VoltageSuppliesTab);
            app.IDLabel_3.Text = 'ID';
            app.VgsSourceEditField = uieditfield(app.VoltageSuppliesTab, 'text');
            app.VgsSourceEditField.Value = 'NI';
            app.IDLabel_4 = uilabel(app.VoltageSuppliesTab);
            app.IDLabel_4.Text = 'ID';
            app.VdsSourceEditField = uieditfield(app.VoltageSuppliesTab, 'text');
            app.VdsSourceEditField.Value = 'NI';
            app.DrainSourceVoltageLabel_2 = uilabel(app.VoltageSuppliesTab);
            app.DrainSourceVoltageLabel_2.Text = 'Drain-Source Voltage ';
            app.GateSourceVoltageLabel_2 = uilabel(app.VoltageSuppliesTab);
            app.GateSourceVoltageLabel_2.Text = 'Gate-Source Voltage ';
            app.InputVoltageLabel = uilabel(app.VoltageSuppliesTab);
            app.InputVoltageLabel.Text = 'Input Voltage ';
            app.PulseDelayusEditField_2Label_10 = uilabel(app.VoltageSuppliesTab);
            app.PulseDelayusEditField_2Label_10.Text = 'Max Voltage (V)';
            app.MaxIVEditField = uieditfield(app.VoltageSuppliesTab, 'numeric');
            app.MaxIVEditField.Limits = [1 20000];
            app.MaxIVEditField.Value = 2000;
            app.IDLabel_2 = uilabel(app.VoltageSuppliesTab);
            app.IDLabel_2.Text = 'ID';
            app.InputVEditField = uieditfield(app.VoltageSuppliesTab, 'text');
            app.InputVEditField.Value = 'COM3';
            app.SafeOperatingAreaLabel = uilabel(app.VoltageSuppliesTab);
            app.SafeOperatingAreaLabel.Text = 'Safe Operating Area';
            app.InitSupplyButton = uibutton(app.VoltageSuppliesTab, 'push');
            app.InitSupplyButton.ButtonPushedFcn = createCallbackFcn(app, @InitSupplyButtonPushed, true);
            app.InitSupplyButton.Text = 'Init Supply';
            app.TemperatureManagerTab = uitab(app.TabGroup2);
            app.TemperatureManagerTab.Title = 'Temperature Manager';
            app.Label_2 = uilabel(app.TemperatureManagerTab);
            app.Label_2.Text = '';
            app.TempPanel = uipanel(app.TemperatureManagerTab);
            app.VonVEditFieldLabel = uilabel(app.TempPanel);
            app.VonVEditFieldLabel.Text = 'Von (V)';
            app.VonVEditField = uieditfield(app.TempPanel, 'numeric');
            app.VonVEditField.Limits = [0 70];
            app.VonVEditField.Value = 50;
            app.IDLabel = uilabel(app.TempPanel);
            app.IDLabel.Text = 'ID';
            app.IDVS_EditField = uieditfield(app.TempPanel, 'text');
            app.IDVS_EditField.Value = 'NI';
            app.Button = uibutton(app.TempPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'SET';
            app.VoltageSupplyLabel = uilabel(app.TempPanel);
            app.VoltageSupplyLabel.Text = 'Voltage Supply';
            app.VoffVEditFieldLabel = uilabel(app.TempPanel);
            app.VoffVEditFieldLabel.Text = 'Voff (V)';
            app.VoffVEditField = uieditfield(app.TempPanel, 'numeric');
            app.VoffVEditField.Limits = [0 70];
            app.VoffVEditField.Value = 20;
            app.TolTLabel = uilabel(app.TemperatureManagerTab);
            app.TolTLabel.Text = 'Tol (°C)';
            app.TolTEditField = uieditfield(app.TemperatureManagerTab, 'numeric');
            app.TolTEditField.Limits = [0 100];
            app.TolTEditField.ValueDisplayFormat = '%11.2g';
            app.TolTEditField.Value = 3;
            app.SetTempCLabel = uilabel(app.TemperatureManagerTab);
            app.SetTempCLabel.Text = 'T1 (°C)';
            app.SetTemp_EditField = uieditfield(app.TemperatureManagerTab, 'numeric');
            app.SetTemp_EditField.Limits = [0 500];
            app.SetTemp_EditField.Value = 75;
            app.SetTempCLabel_2 = uilabel(app.TemperatureManagerTab);
            app.SetTempCLabel_2.Text = 'Sensor Temp (°C)';
            app.SetTempCLabel_3 = uilabel(app.TemperatureManagerTab);
            app.SetTempCLabel_3.Text = 'T2 (°C)';
            app.SetTemp2_EditField = uieditfield(app.TemperatureManagerTab, 'numeric');
            app.SetTemp2_EditField.Limits = [0 500];
            app.SetTemp2_EditField.Enable = 'off';
            app.SetTemp2_EditField.Value = 80;
            app.SetTempCLabel_4 = uilabel(app.TemperatureManagerTab);
            app.SetTempCLabel_4.Text = 'Step (°C)';
            app.SetdTemp_EditField = uieditfield(app.TemperatureManagerTab, 'numeric');
            app.SetdTemp_EditField.Limits = [0 500];
            app.SetdTemp_EditField.ValueChangedFcn = createCallbackFcn(app, @SetdTemp_EditFieldValueChanged, true);
            app.ReadTemp_EditField = uieditfield(app.TemperatureManagerTab, 'numeric');
            app.ReadTemp_EditField.Limits = [-Inf 1000];
            app.ReadTemp_EditField.Editable = 'off';
            app.ReadTemp_EditField.Value = 25;
            app.FeedbackButton = uibutton(app.TemperatureManagerTab, 'state');
            app.FeedbackButton.ValueChangedFcn = createCallbackFcn(app, @FeedbackButtonValueChanged, true);
            app.FeedbackButton.Text = 'FEEDBACK';
            app.FeedbackButton.Value = true;
            app.MessageTextAreaLabel = uilabel(app.LeftPanel);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.LeftPanel);
            app.LampLabel = uilabel(app.LeftPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.LeftPanel);
            app.MessageTextAreaLabel_2 = uilabel(app.LeftPanel);
            app.MessageTextAreaLabel_2.Text = 'File';
            app.FileCheckBox = uicheckbox(app.LeftPanel);
            app.FileCheckBox.ValueChangedFcn = createCallbackFcn(app, @FileCheckBoxValueChanged, true);
            app.FileCheckBox.Text = '';
            app.FolderPathEditFieldLabel = uilabel(app.LeftPanel);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.LeftPanel, 'text');
            app.FolderPathEditField.ValueChangedFcn = createCallbackFcn(app, @FolderPathEditFieldValueChanged, true);
            app.FolderPathEditField.Value = 'C:\Users\irnew\MATLAB Drive\IVTracer_Russo\FolderRisultati';
            app.DeviceEditFieldLabel = uilabel(app.LeftPanel);
            app.DeviceEditFieldLabel.Text = 'Device';
            app.DeviceEditField = uieditfield(app.LeftPanel, 'text');
            app.DeviceEditField.Value = '60us';
            app.IVF_ButtonGroup = uibuttongroup(app.LeftPanel);
            app.IVFRUNButton = uibutton(app.IVF_ButtonGroup, 'push');
            app.IVFRUNButton.ButtonPushedFcn = createCallbackFcn(app, @IVFRUNButtonPushed, true);
            app.IVFRUNButton.Text = 'RUN';
            app.SourceDropDownType = uidropdown(app.IVF_ButtonGroup);
            app.SourceDropDownType.Items = {'TRANSFER', 'OUTPUT', 'BOTH', 'TRANSFER + TEMP', 'OUTPUT + TEMP', 'BOTH + TEMP'};
            app.SourceDropDownType.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.SourceDropDownType.Value = '1';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '';
            app.PILabel = uilabel(app.LeftPanel);
            app.PILabel.Text = 'PI ';
            app.KpEditFieldLabel = uilabel(app.LeftPanel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.KpEditField.Limits = [0 100];
            app.KpEditField.Value = 0.7;
            app.TolVEditFieldLabel = uilabel(app.LeftPanel);
            app.TolVEditFieldLabel.Text = 'Tol (V)';
            app.TolVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TolVEditField.Limits = [0 100];
            app.TolVEditField.ValueDisplayFormat = '%11.2g';
            app.TolVEditField.Value = 0.05;
            app.KiEditFieldLabel = uilabel(app.LeftPanel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.LeftPanel, 'numeric');
            app.KiEditField.Limits = [0 100];
            app.KiEditField.Value = 0.2;
            app.VCompButton = uibutton(app.LeftPanel, 'state');
            app.VCompButton.ValueChangedFcn = createCallbackFcn(app, @VCompButtonValueChanged, true);
            app.VCompButton.Text = 'COMP';
            app.VCompButton.Value = true;
            app.Comp_iterEditFieldLabel = uilabel(app.LeftPanel);
            app.Comp_iterEditFieldLabel.Text = 'Comp_iter';
            app.Comp_iterEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Comp_iterEditField.Value = 3;
            app.VCompButton_2 = uibutton(app.LeftPanel, 'state');
            app.VCompButton_2.ValueChangedFcn = createCallbackFcn(app, @VCompButton_2ValueChanged, true);
            app.VCompButton_2.Text = 'CALIB';
            app.RightPanel = uipanel(app.GridLayout);
            app.ch1 = uiaxes(app.RightPanel);
            title(app.ch1, 'Oscilloscope Channel 1')
            xlabel(app.ch1, 'Time (s)')
            ylabel(app.ch1, 'Voltage (V)')
            zlabel(app.ch1, 'Z')
            app.ch2 = uiaxes(app.RightPanel);
            title(app.ch2, 'Oscilloscope Channel 2')
            xlabel(app.ch2, 'Time (s)')
            ylabel(app.ch2, 'Voltage (V)')
            zlabel(app.ch2, 'Z')
            app.ch3 = uiaxes(app.RightPanel);
            title(app.ch3, 'Oscilloscope Channel 3')
            xlabel(app.ch3, 'Time (s)')
            ylabel(app.ch3, 'Voltage (V)')
            zlabel(app.ch3, 'Z')
            app.ch4 = uiaxes(app.RightPanel);
            title(app.ch4, 'Oscilloscope Channel 4')
            xlabel(app.ch4, 'Time (s)')
            ylabel(app.ch4, 'Voltage (V)')
            zlabel(app.ch4, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Lamp.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IV_forwardTDS544Alatest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end